classdef PERCEPT_DBSartDETECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReviewButton                 matlab.ui.control.Button
        ManualWindowSelectionButton  matlab.ui.control.Button
        ZoomSlider                   matlab.ui.control.Slider
        ZoomSliderLabel              matlab.ui.control.Label
        TimesSlider                  matlab.ui.control.Slider
        Move                         matlab.ui.control.Button
        Select                       matlab.ui.control.Button
        ZoomOUT                      matlab.ui.control.Button
        ZoomIN                       matlab.ui.control.Button
        PrevButton                   matlab.ui.control.Button
        NextXButton                  matlab.ui.control.Button
        CheckDBSONspikeButton        matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MainApp % Description
        checkflag
        DATAorg2
        BSdata
        BSdata_out
        TARGETsrate2
        medval2
        
        
        IDX2
        selectCHANdata2
        selectCHANdata2_marked
        srate2
        

        it2
        maxval2
        threshMULTIPLIER


        x
        y
        x2

        winBOTTOM
        winUPPER
        winCHANGE
        zoomCHANGE

        CHECKflag
        PERCEPTartNUM
        PERCEPTartLAT
        

        epochVALsp2


    end

    
    methods (Access = public)
        
        function updatePERCEPT_ARTLAT(app, artlats)
            app.PERCEPTartLAT = artlats;
        end

        function updatePERCEPT_DATA(app,data)
            app.BSdata = data; 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, checkflag, DATAorg, srate, winLOC, EEGdata, TARGETsrate)

            displaySIZE = get(0,'screensize'); displaySIZE = displaySIZE(end-1:end);
            app.UIFigure.Position = [winLOC(1:2) 1372,799]; 
            
            % size(DATAorg)
            app.DATAorg2 = DATAorg;
            
            % Default chans : Cz, FCz

            % Turn srate into double
            srate=str2double(srate);
            
            % app.UIFigure.Visible = 'off';
            TrimPERCEPT(app,app.DATAorg2,app.UIFigure.Position,EEGdata);
            selection = uiconfirm(app.UIFigure,'Click OK to continue','');

            %Select one of channels: LEFT STN here
            selectCHANdata = app.BSdata(1).DataVec';
            % figure; plot(selectCHANdata)

            if ~isempty(find(isnan(selectCHANdata)))
                disp('Disconnections were found then fixed.')
                selectCHANdata(find(isnan(selectCHANdata)))=1;
            end

            selectCHANdata22 = smoothdata(abs(selectCHANdata));
            selectCHANdata22 = selectCHANdata22/max(selectCHANdata22);
            [a,tempPTS] = findpeaks(selectCHANdata22,'MinPeakWidth',srate*.05,'MinPeakDistance',srate*20,'MinPeakHeight',.5);
            figure; plot(selectCHANdata22); xline(tempPTS,'r')
            title([num2str(length(tempPTS))])


            DBSstart = tempPTS;
            DBSend = DBSstart;


            if length(DBSstart) ~= length(DBSend)
                errordlg('DBS art detection WRONG!')
            end

            if length(DBSstart) == length(DBSend)
                app.x = zeros(1,length(DBSstart));
                app.y = zeros(1,length(DBSstart));
            end


            % Define timepoints according to the given srate
            % timepoints = 1/srate:1/srate:length(DATAorgAVG)/srate;
            plot(app.UIAxes,selectCHANdata,'k'); hold on
            xline(app.UIAxes,DBSstart,'r');
            try
                close(1)
            catch
            end
            % This is how you set X/YLim in app designer
            % xlim(app.UIAxes,[min(timepoints) max(timepoints)])
            xlim(app.UIAxes,[1 length(selectCHANdata)])
            title(app.UIAxes, ['CHANNELS: LEFT STN'])

            plot(app.UIAxes_2,selectCHANdata,'k');
            title(app.UIAxes_2, 'Zoomed in epoch')
            xlim(app.UIAxes_2,[1 length(selectCHANdata)])


            app.IDX2 = [DBSstart; DBSend]';
            app.selectCHANdata2=selectCHANdata;
            app.selectCHANdata2_marked = selectCHANdata;
            app.srate2=srate;
            app.it2 = 1;
            app.winCHANGE=0;
            app.CHECKflag=false;
            app.zoomCHANGE=0;
            app.epochVALsp2 = 24;
            app.TARGETsrate2 = str2double(TARGETsrate); 
            app.MainApp=mainapp;


        end

        % Button pushed function: CheckDBSONspikeButton
        function CheckDBSONspikeButtonPushed(app, event)
            % [tempIDX2,artAMP] = Click2FindApp(selectCHANdata, IDX, srate)
            app.ManualWindowSelectionButton.Enable = 'off';
            app.CHECKflag=true;

            app.CheckDBSONspikeButton.Enable = 'off';
            app.Move.Enable = 'on';
            app.ZoomOUT.Enable = 'on';
            app.ZoomIN.Enable = 'on';
            app.Select.Enable = 'on';
            app.TimesSlider.Enable = 'on';

            % selectCHANdata = app.selectCHANdata2;
            srate=app.srate2;


            IDX = app.IDX2;


            padding = srate*20;

            it = app.it2;
             % Enable/Disable Next/Prev buttons
            if it==1
                app.NextXButton.Enable = 'on';
                app.PrevButton.Enable = 'off';
            end
            if it>1
                app.NextXButton.Enable = 'on';
                app.PrevButton.Enable = 'on';
            end
            if it == size(IDX,1)
                app.NextXButton.Enable = 'off';
                app.PrevButton.Enable = 'on';
            end

            if floor(IDX(it,1)-srate*20) < 0
                xlimVAL = [1 floor(IDX(it,2)+(srate*20))];
            elseif floor(IDX(it,2)+srate*20) > length(app.selectCHANdata2)
                xlimVAL = [floor(IDX(it,1)-srate*20) length(app.selectCHANdata2)];
            else
                xlimVAL = [floor(IDX(it,1)-padding) floor(IDX(it,2)+padding)];
            end

            app.winUPPER = xlimVAL;


            % xlimVAL
            xlim(app.UIAxes, xlimVAL);
            title(app.UIAxes,'Broader epoch range')
            % hold off;


            win = [(floor(IDX(it,1))-(srate*2)) (floor(IDX(it,1))+(srate*2))]; 
            winRANGE = diff(win); 
            winINCREMENT = winRANGE*(1-app.zoomCHANGE); 
            winINCREMENT = winINCREMENT/2;
            % win = [(floor(IDX(it,1))+(srate*4))+winINCREMENT, (floor(IDX(it,1))+(srate*8))-winINCREMENT] + app.winCHANGE;
            win = [floor(IDX(it,1))-winINCREMENT, floor(IDX(it,1))+winINCREMENT] + app.winCHANGE; 


            win = floor(win);

            app.winBOTTOM=win;

            % win
            xlim(app.UIAxes_2,win);
            temp=double(floor(app.selectCHANdata2(win(1):win(2))));

            winVAL =  [min(temp) max(temp)];
            marg = .1;
            scaleVAL1 = winVAL + [-1 1]*diff(winVAL)*marg;
            scaleVAL2 = winVAL + [1 -1]*diff(winVAL)*marg;
            scaleVAL3 = winVAL + [1 1]*diff(winVAL)*marg;
            
            % round([min(temp) max(temp)])
            if max(temp) > 0 && min(temp) > 0 
                ylim(app.UIAxes_2,scaleVAL1);
            elseif max(temp) < 0 && min(temp) < 0 
                ylim(app.UIAxes_2,scaleVAL2);
            elseif max(temp) > 0 && min(temp) < 0 
                ylim(app.UIAxes_2,scaleVAL1);
            end


            title(app.UIAxes_2,{['Trial#: ' num2str(it) ' / ' num2str(size(IDX,1))]; 'Zoomed in window'})
            xline(app.UIAxes, win, 'g');

            Ytemp = get(app.UIAxes,'YLim');

            hold(app.UIAxes, 'on')
            x_points = [win(1), win(1), win(2), win(2)];
            y_points = [Ytemp(1) Ytemp(2) Ytemp(2) Ytemp(1)];
            fill(app.UIAxes, x_points, y_points, [0 1 0],'FaceAlpha',.1);


            % hold(app.UIAxes,'on')
            % fill(app.UIAxes, x_points, y_points, [0 1 0],'FaceAlpha',.1);

            try
                close(1)
            catch
            end


        end

        % Button pushed function: ReviewButton
        function ReviewButtonPushed(app, event)
             % selectCHANdata = app.selectCHANdata2;
            
            % Setup a DBS_ARTIFACT marking value based on a median across
            % throughout the entire data in the selected channels
            % DBS_ARTIFACTS are manually marked because downsampling the
            % data will mess up with the EV latencies.
            medval = median(app.selectCHANdata2); %This is the median value from selected channels (Cz, FCz at default)
            medMULTIPLIER = 10^4;
            % medval
            if medval<0; medval = -1*medval; end
            if abs(medval) <1; medval = medMULTIPLIER; end

            app.medval2 = medval; app.threshMULTIPLIER = medMULTIPLIER;
            
            DBSONartLAT = app.x; %DBS_ARTIFACT latencies @sampling rate:250 Hz

            PerceptManualAdj(app,app.selectCHANdata2, DBSONartLAT, app.epochVALsp2, app.srate2)

            app.ReviewButton.Enable = 'off';
            app.DoneButton.Enable = 'on';

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)

            % app.PERCEPTartLAT
            app.selectCHANdata2_marked(app.PERCEPTartLAT)= app.medval2*app.threshMULTIPLIER;

            BSdata = app.BSdata; %BSdata


            %Up-sample from 250 to 500Hz to match the EEG data sampling rate
            for chan = 1:length(BSdata)
                [BSdata(chan).DataRes, BSdata(chan).time] = MarioReSample(BSdata(chan).DataVec, BSdata(chan).srate, app.TARGETsrate2);
            end
            [BSdata(chan+1).DataRes, BSdata(chan+1).time] = MarioReSample(app.selectCHANdata2_marked, BSdata(chan).srate, app.TARGETsrate2);

            Pdata = BSdata(chan+1).DataRes;

            app.BSdata_out = BSdata; % TARGET sampling rate

            assignin('base','BSdata_ART_MARKED',BSdata);

            DBSONartLAT2 = find(Pdata>app.medval2*app.threshMULTIPLIER*.6);  
            DBSONartLAT2(find(diff(DBSONartLAT2)<app.srate2)+1)=[];
            DBSONartLAT2 = DBSONartLAT2+1;

            tempDATA = BSdata(1).DataRes; tempDATA2 =[];
            app.PERCEPTartLAT = DBSONartLAT2;


            tempDATA = app.BSdata_out(1).DataRes;
            % size(tempDATA)
            for it = 1:length(app.PERCEPTartLAT)
                
                tempDATA2(:,it)=tempDATA( (app.PERCEPTartLAT(it)-(app.epochVALsp2 / (1000/app.TARGETsrate2))):(app.PERCEPTartLAT(it)+(app.epochVALsp2 / (1000/app.TARGETsrate2))) );
            end

            PERCEPTtrace = tempDATA2;
            tNUM = size(PERCEPTtrace,2);

            % figure('Position',[500 500 1500 1500]);
            % 
            % for it = 1:length(app.PERCEPTartLAT)
            %     subplot(floor(length(app.PERCEPTartLAT)/4)+1, 4, it)
            %     plot(-app.epochVALsp2:1000/app.TARGETsrate2:app.epochVALsp2,zscore(PERCEPTtrace(:,it)),'k-'); hold on
            %     xline(0,'k')
            %     tempylimVAL = get(gca,'YLim');
            %     set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2],'XLim',[-1 1]*app.epochVALsp2)
            %     axis square
            %     title({ ['ARTIFACT alignment (sampling rate: ' num2str(app.TARGETsrate2) ' Hz)'] ; [num2str(it) ' / ' num2str(tNUM)] })
            %     xlabel({'Time (ms)' 'Zero is DBS ARTIFACT'})
            %     ylabel('Normalized Amplitude')
            % end


            % figure; plot(tempDATA); xline(app.PERCEPTartLAT,'r')

            app.PERCEPTartNUM = length(app.PERCEPTartLAT);
            % % % app.PERCEPTartLAT = app.PERCEPTartLAT;
            % % 
            app.checkflag = true;
            % Call main app's updatePARAMS function
            updatePERCEPT(app.MainApp,app.checkflag,app.BSdata_out,app.PERCEPTartNUM,app.PERCEPTartLAT)


            %Close the dialogue
            delete(app)
            
        end

        % Button pushed function: NextXButton
        function NextXButtonPushed(app, event)


            if app.it2 <= length(app.IDX2)
                app.it2 = app.it2 + 1;
                CheckDBSONspikeButtonPushed(app, event)
            else
                app.NextXButton.Enable = 'off';

            end


        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % app.PrevButtonPushed.Enable = 'on';
            app.it2 = app.it2 - 1;
            % disp(app.it2)
            CheckDBSONspikeButtonPushed(app, event)
        end

        % Button pushed function: ZoomIN
        function ZoomINPushed(app, event)

            zoom(app.UIAxes_2,'on')

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.CHECKflag
                eventname = event.EventName;
                % event
                switch eventname
                    case 'WindowMouseMotion'
                        % Obtain Coordinates of mouse for each of the 4 axes
                        currentPoint1 = app.UIAxes_2.CurrentPoint(1,1:3);
                        x1 = currentPoint1(1);
                        y1 = currentPoint1(2);
                        
                         % Obtain Coordinates of mouse for each of the 4 axes
                        currentPoint2 = app.UIAxes.CurrentPoint(1,1:3);
                        x2 = currentPoint2(1); app.x2 = x2; 
                        y2 = currentPoint2(2);
                        

                        % Change pointer to crosshair when within axes.
                        if (app.UIAxes_2.XLim(1)<x1)&&(x1<app.UIAxes_2.XLim(2)) && (app.UIAxes_2.YLim(1)<y1)&&(y1<app.UIAxes_2.YLim(2))
                            set(app.UIFigure,'Pointer','cross');
                        elseif(app.UIAxes.XLim(1)<x2)&&(x2<app.UIAxes.XLim(2)) && (app.UIAxes.YLim(1)<y2)&&(y2<app.UIAxes.YLim(2))
                            set(app.UIFigure,'Pointer','cross');
                        else
                            set(app.UIFigure,'Pointer','arrow'); % When outside of axes, return pointer to arrow
                        end
                        % 
                        % % Change pointer to crosshair when within axes.
                        % if (app.UIAxes_2.XLim(1)<x1)&&(x1<app.UIAxes_2.XLim(2)) && (app.UIAxes_2.YLim(1)<y1)&&(y1<app.UIAxes_2.YLim(2))
                        %     set(app.UIFigure,'Pointer','cross');
                        % else
                        %     set(app.UIFigure,'Pointer','arrow'); % When outside of axes, return pointer to arrow
                        % end

                end

            end
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            % event
            eventname = event.EventName;
            eventBUTTON = event.Button;

            if app.CHECKflag

                if eventBUTTON == 1
                    switch eventname

                        case 'Hit'
                            % When mouse is pressed, get coordinates
                            currentPoint1 = get(app.UIAxes_2,'CurrentPoint');
                            xx=ceil(currentPoint1(1,1));
                            yy=currentPoint1(1,2);

                            tempRANGE = 3;
                            tempEPOCH = app.selectCHANdata2((xx-tempRANGE):(xx+tempRANGE));

                            if tempEPOCH(tempRANGE) > 0
                                [~,tempX] = max(tempEPOCH); %tempX
                            elseif tempEPOCH(tempRANGE) < 0
                                [~,tempX] = min(tempEPOCH);
                            end
                            % tempX
                            tempX = tempX + (xx-tempRANGE) -1 ;
                            % tempX=ceil(tempX)-1;


                            % app.x
                            % app.it2
                            if app.x(app.it2)==0
                                % xline(app.UIAxes_2,tempX,'c','LineWidth',3);
                                hold(app.UIAxes_2,'on');
                                plot(app.UIAxes_2, tempX,app.selectCHANdata2(tempX), 'ro','MarkerFaceColor','r','MarkerSize',8);
                            else
                                hold(app.UIAxes_2,'off');
                                plot(app.UIAxes_2,app.selectCHANdata2,'k');
                                win=app.winBOTTOM;
                                it = app.it2;
                                IDX = app.IDX2;

                                xlim(app.UIAxes_2,win);
                                temp=double(app.selectCHANdata2(win(1):win(2)));
                                winVAL =  [min(temp) max(temp)];
                                marg = .1;
                                scaleVAL1 = winVAL + [-1 1]*diff(winVAL)*marg;
                                scaleVAL2 = winVAL + [1 -1]*diff(winVAL)*marg;
                                scaleVAL3 = winVAL + [1 1]*diff(winVAL)*marg;

                                % round([min(temp) max(temp)])
                                if max(temp) > 0 && min(temp) > 0
                                    ylim(app.UIAxes_2,scaleVAL1);
                                elseif max(temp) < 0 && min(temp) < 0
                                    ylim(app.UIAxes_2,scaleVAL2);
                                elseif max(temp) > 0 && min(temp) < 0
                                    ylim(app.UIAxes_2,scaleVAL1);
                                end


                                title(app.UIAxes_2,{['Trial#: ' num2str(it) ' / ' num2str(size(IDX,1))]; 'Zoom into the artifact then press anykey'})

                                restXtemp = app.x( setxor(1:length(app.x),app.it2) );

                                if ~isempty(restXtemp(restXtemp~=0))
                                    % xline(app.UIAxes_2, restXtemp(restXtemp~=0), 'c','LineWidth',3);
                                end


                                % xline(app.UIAxes_2, tempX, 'c','LineWidth',3);

                                hold(app.UIAxes_2,'on'); 
                                
                                plot(app.UIAxes_2, [restXtemp(restXtemp~=0) tempX],app.selectCHANdata2([restXtemp(restXtemp~=0) tempX]), 'ro','MarkerFaceColor','r','MarkerSize',8);

                                try
                                    close(1)
                                catch
                                end
                            end

                            app.x(app.it2) = tempX; %app.x
                            app.y(app.it2) = floor(currentPoint1(1,2)); %app.y

                            % DONE button activated when all ART
                            % latencies were found thus no zero values
                            % in app.x
                            if ~ismember(0,app.x)
                                app.ReviewButton.Enable = 'on';
                            end

                    end
                end

                if eventBUTTON == 3
                    % app.cflag=true;
                    zoom(app.UIAxes_2,'off')
                    pan(app.UIAxes_2, 'off')
                end
                figure(app.UIFigure)
            end
        end

        % Button pushed function: ZoomOUT
        function ZoomOUTButtonPushed(app, event)
            zoom(app.UIAxes_2,'out')
        end

        % Button pushed function: Select
        function SelectButtonPushed(app, event)
            zoom(app.UIAxes_2,'off')
            pan(app.UIAxes_2, 'off')
        end

        % Button pushed function: Move
        function MoveButtonPushed(app, event)
            pan(app.UIAxes_2, 'on')


        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            % event
            % key
            if app.CHECKflag
                if strcmp(event.EventName,'WindowKeyPress')

                    % key
                    switch key
                        case 'a'
                            zoom(app.UIAxes_2,'on')
                        case 's'
                            zoom(app.UIAxes_2,'out')
                        case 'd'
                            pan(app.UIAxes_2, 'on');
                        case 'z'
                            if app.it2 ~= 1
                                app.it2 = app.it2-1;
                                CheckDBSONspikeButtonPushed(app, event)
                            end
                        case 'x'
                            if app.it2 <= length(app.IDX2)
                                if app.it2 < length(app.IDX2)
                                    app.it2 = app.it2 + 1;
                                end
                                CheckDBSONspikeButtonPushed(app, event)
                            else
                                app.NextXButton.Enable = 'off';
                            end
                        case 'leftarrow'
                            slideVAL = app.TimesSlider.Value;
                            app.TimesSlider.Value = app.TimesSlider.Value - .2;
                            TimesSliderValueChanged(app, event)
                        case 'rightarrow'
                            slideVAL = app.TimesSlider.Value;
                            app.TimesSlider.Value = app.TimesSlider.Value + .2;
                            TimesSliderValueChanged(app, event)
                        case 'uparrow'
                            zoomVAL = app.ZoomSlider.Value;
                            app.ZoomSlider.Value = app.ZoomSlider.Value + 2;
                            ZoomSliderValueChanged(app, event)
                        case 'downarrow'
                            zoomVAL = app.ZoomSlider.Value;
                            app.ZoomSlider.Value = app.ZoomSlider.Value - 2;
                            ZoomSliderValueChanged(app, event)
                    end


                end
            end
        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            value = app.TimesSlider.Value;
            app.winCHANGE = (value*app.srate2);
            % app.winUPPER
            hold(app.UIAxes,'off')
            plot(app.UIAxes,app.selectCHANdata2,'k'); %hold on
            xline(app.UIAxes,app.IDX2(:,1),'r');
            xlim(app.UIAxes, app.winUPPER);
            title(app.UIAxes,'Broader epoch range')
            try close(1);catch;end


            CheckDBSONspikeButtonPushed(app, event)
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            value = app.ZoomSlider.Value;
            value = value/100;
            app.zoomCHANGE = value;

            % app.zoomCHANGE
            hold(app.UIAxes, 'off')
            plot(app.UIAxes,app.selectCHANdata2,'k'); hold on
            xline(app.UIAxes,app.IDX2(:,1),'g');
            xlim(app.UIAxes, app.winUPPER);
            title(app.UIAxes,'Broader epoch range')
            try
                close(1)
            catch
            end

            CheckDBSONspikeButtonPushed(app, event)
        end

        % Button pushed function: ManualWindowSelectionButton
        function ManualWindowSelectionButtonPushed(app, event)
            DBSstart = Click2DefineWin(app.selectCHANdata2);
            app.IDX2 = [DBSstart; DBSstart]';
            app.x = zeros(1,length(DBSstart));
            % % Define timepoints according to the given srate
            % % timepoints = 1/srate:1/srate:length(DATAorgAVG)/srate;
            figure; plot(app.UIAxes,app.selectCHANdata2,'k');
            xline(app.UIAxes,app.IDX2(:,1),'r')

            try
                close(1)
            catch
            end
            % This is how you set X/YLim in app designer
            % % xlim(app.UIAxes,[min(timepoints) max(timepoints)])
            xlim(app.UIAxes,[1 length(app.selectCHANdata2)])
            title(app.UIAxes, ['CHANNELS: LEFT STN'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wider Scale')
            xlabel(app.UIAxes, 'Time (Sample Point)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Zoomed in')
            xlabel(app.UIAxes_2, 'Time (Sample Point)')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.CheckDBSONspikeButton = uibutton(app.UIFigure, 'push');
            app.CheckDBSONspikeButton.ButtonPushedFcn = createCallbackFcn(app, @CheckDBSONspikeButtonPushed, true);
            app.CheckDBSONspikeButton.Text = {'Check'; 'DBS ON spike'};
            app.NextXButton = uibutton(app.UIFigure, 'push');
            app.NextXButton.ButtonPushedFcn = createCallbackFcn(app, @NextXButtonPushed, true);
            app.NextXButton.Enable = 'off';
            app.NextXButton.Text = 'Next [X]';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Enable = 'off';
            app.PrevButton.Text = 'Previous [Z]';
            app.ZoomIN = uibutton(app.UIFigure, 'push');
            app.ZoomIN.ButtonPushedFcn = createCallbackFcn(app, @ZoomINPushed, true);
            app.ZoomIN.Enable = 'off';
            app.ZoomIN.Text = 'Zoom IN [A]';
            app.ZoomOUT = uibutton(app.UIFigure, 'push');
            app.ZoomOUT.ButtonPushedFcn = createCallbackFcn(app, @ZoomOUTButtonPushed, true);
            app.ZoomOUT.Enable = 'off';
            app.ZoomOUT.Text = 'Zoom OUT [S]';
            app.Select = uibutton(app.UIFigure, 'push');
            app.Select.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.Select.Enable = 'off';
            app.Select.Text = 'Select';
            app.Move = uibutton(app.UIFigure, 'push');
            app.Move.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.Move.Enable = 'off';
            app.Move.Text = 'Navigate [D]';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.Limits = [-20 20];
            app.TimesSlider.MajorTicks = [-20 -10 0 10 20];
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.TimesSlider.Enable = 'off';
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.Limits = [40 90];
            app.ZoomSlider.MajorTicks = [40 50 60 70 80 90];
            app.ZoomSlider.MajorTickLabels = {'x1.65', 'x2', 'x2.5', 'x3.3', 'x5', 'x10'};
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.Value = 40;
            app.ManualWindowSelectionButton = uibutton(app.UIFigure, 'push');
            app.ManualWindowSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ManualWindowSelectionButtonPushed, true);
            app.ManualWindowSelectionButton.Text = {'Manual Window'; 'Selection'};
            app.ReviewButton = uibutton(app.UIFigure, 'push');
            app.ReviewButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewButtonPushed, true);
            app.ReviewButton.Enable = 'off';
            app.ReviewButton.Text = 'Review';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PERCEPT_DBSartDETECT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end