classdef PerceptManualAdj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Next        matlab.ui.control.Button
        Prev        matlab.ui.control.Button
        DoneButton  matlab.ui.control.Button
        MoveRight   matlab.ui.control.Button
        MoveLeft    matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp
        it2
        tNUM
        DBSONartLAT2
        data2 % entire continuous data
        PERCEPTtrace2 % epoched data
        epochVALsp2
        srate2
    end
    
    methods (Access = private)
        
        function CheckPlot(app, data, lats, epochVALsp, it)
            % Enable/Disable Next/Prev buttons
            if it==1
                app.Next.Enable = 'on';
                app.Prev.Enable = 'off';
            end
            if it>1
                app.Next.Enable = 'on';
                app.Prev.Enable = 'on';
            end
            if it == length(lats)
                app.Next.Enable = 'off';
                app.Prev.Enable = 'on';
            end

            PERCEPTtrace=data( (lats(it)-(app.epochVALsp2 / (1000/app.srate2))):(lats(it)+(app.epochVALsp2 / (1000/app.srate2))) );
            hold(app.UIAxes,"off");

            plot(app.UIAxes,-epochVALsp:1000/app.srate2:epochVALsp,zscore(PERCEPTtrace),'k-'); hold(app.UIAxes,"on");
            xline(app.UIAxes,0,'k')
            tempylimVAL = get(app.UIAxes,'YLim');
            set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2])
            axis square
            title(app.UIAxes,{ 'ARTIFACT alignment' ; [num2str(it) ' / ' num2str(length(lats))] })
            xlabel(app.UIAxes,{'Time (ms)' 'Zero is DBS ARTIFACT'})
            ylabel(app.UIAxes,'Normalized Amplitude')

            close all
        end

        function CheckALL_Plot(app, data, lats, epochVALsp)
            figure('Position',[500 500 1500 1500]);
            for it = 1:length(lats)
                subplot(floor(length(lats)/4)+1, 4, it)
                PERCEPTtrace=data( (lats(it)-(app.epochVALsp2 / (1000/app.srate2))):(lats(it)+(app.epochVALsp2 / (1000/app.srate2))) );

                plot(-epochVALsp:1000/app.srate2:epochVALsp,zscore(PERCEPTtrace),'k-'); hold(app.UIAxes,"on");
                xline(0,'k')
                tempylimVAL = get(gca,'YLim');
                set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2], 'XLim', [-1 1]*epochVALsp)
                axis square
                title({ ['ARTIFACT alignment (sampling rate: ' num2str(app.srate2) ' Hz)']; [num2str(it) ' / ' num2str(length(lats))] })
                xlabel({'Time (ms)' 'Zero is DBS ARTIFACT'})
                ylabel('Normalized Amplitude')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data, lats, epochVALsp, srate)

            app.DBSONartLAT2 = lats; 
            app.data2 = data; 
            app.epochVALsp2 = epochVALsp;  
            app.tNUM = length(lats);
            app.it2 = 1;
            app.srate2 = srate; 
            close all
            
            PERCEPTtrace(:,app.it2)=app.data2( (app.DBSONartLAT2(app.it2)-(app.epochVALsp2 / (1000/app.srate2))):(app.DBSONartLAT2(app.it2)+(app.epochVALsp2 / (1000/app.srate2))) );
            plot(app.UIAxes,-epochVALsp:1000/app.srate2:epochVALsp,zscore(PERCEPTtrace(:,app.it2)),'k-'); hold(app.UIAxes,"on");
            xline(app.UIAxes,0,'k')
            tempylimVAL = get(app.UIAxes,'YLim');
            set(app.UIAxes, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2], 'XLim', [-1 1]*epochVALsp)
            axis square
            title(app.UIAxes,{ 'ARTIFACT alignment' ; [num2str(app.it2) ' / ' num2str(app.tNUM)] })
            xlabel(app.UIAxes,{'Time (ms)' 'Zero is DBS ARTIFACT'})
            ylabel(app.UIAxes,'Normalized Amplitude')
            close all
            
            app.Next.Enable = 'on';
            app.Prev.Enable = 'off';


            app.MainApp=mainapp;
        end

        % Button pushed function: MoveLeft
        function MoveLeftButtonPushed(app, event)
            app.DBSONartLAT2(app.it2) = app.DBSONartLAT2(app.it2)-1;
            CheckPlot(app, app.data2, app.DBSONartLAT2, app.epochVALsp2, app.it2)
        end

        % Button pushed function: MoveRight
        function MoveRightButtonPushed(app, event)
            app.DBSONartLAT2(app.it2) = app.DBSONartLAT2(app.it2)+1;
            CheckPlot(app, app.data2, app.DBSONartLAT2, app.epochVALsp2, app.it2)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % CheckALL_Plot(app, app.data2, app.DBSONartLAT2, app.epochVALsp2)

            % Call main app's updatePARAMS function
            updatePERCEPT_ARTLAT(app.MainApp, app.DBSONartLAT2)

            delete(app)
        end

        % Button pushed function: Prev
        function PrevButtonPushed(app, event)
            app.it2 = app.it2-1;
            CheckPlot(app, app.data2, app.DBSONartLAT2, app.epochVALsp2, app.it2)
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            app.it2 = app.it2+1;
            CheckPlot(app, app.data2, app.DBSONartLAT2, app.epochVALsp2, app.it2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MoveLeft = uibutton(app.UIFigure, 'push');
            app.MoveLeft.ButtonPushedFcn = createCallbackFcn(app, @MoveLeftButtonPushed, true);
            app.MoveLeft.Text = '-1';
            app.MoveRight = uibutton(app.UIFigure, 'push');
            app.MoveRight.ButtonPushedFcn = createCallbackFcn(app, @MoveRightButtonPushed, true);
            app.MoveRight.Text = '+1';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Prev = uibutton(app.UIFigure, 'push');
            app.Prev.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.Prev.Text = 'Previous';
            app.Next = uibutton(app.UIFigure, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PerceptManualAdj(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end