classdef SetParam2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SamplingRateWhenMergerdLabel_3  matlab.ui.control.Label
        SetoutputfilenameLabel          matlab.ui.control.Label
        OUTPUT_FILENAME_ReadEditField_2  matlab.ui.control.EditField
        WillbeautomaticallyloadedLabel_2  matlab.ui.control.Label
        WillbeautomaticallyloadedLabel  matlab.ui.control.Label
        PERCEPTSamplingRateTextArea     matlab.ui.control.TextArea
        PERCEPTSamplingRateTextAreaLabel  matlab.ui.control.Label
        HzLabel_5                       matlab.ui.control.Label
        EEGSamplingRateTextArea         matlab.ui.control.TextArea
        EEGSamplingRateTextAreaLabel    matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CzFCzdefaultselectionLabel      matlab.ui.control.Label
        SamplingRateWhenMergerdLabel_2  matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        PERCEPT_ReadEditField           matlab.ui.control.EditField
        PERCEPTinputButton              matlab.ui.control.Button
        SelectajsonfileLabel            matlab.ui.control.Label
        Step2SelectPERCEPTdataLabel     matlab.ui.control.Label
        TargetSamplingRateTextArea      matlab.ui.control.TextArea
        SamplingRateWhenMergerdLabel    matlab.ui.control.Label
        ChannelsListBox                 matlab.ui.control.ListBox
        ChannelsListBoxLabel            matlab.ui.control.Label
        Step3SetGeneralParametersLabel  matlab.ui.control.Label
        Step1SelectEEGdataLabel         matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        OUTPUT_ReadEditField            matlab.ui.control.EditField
        OUTPUT_Button                   matlab.ui.control.Button
        EEG_ReadEditField               matlab.ui.control.EditField
        EEGinputButton                  matlab.ui.control.Button
        OutputDirLabel                  matlab.ui.control.Label
        SelectasetfileLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
        EEGinfile 
        PERCEPTinfile  % Input file (*.*)
        EEGoutfile  % Output file (*.set)
        EEGchans 
        EEGsrate
        PERCEPTsrate  
        FINALsrate 
        DATAorg % EEG struct
        perceptDATAorg % Load from json

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, winLOC)
            
            displaySIZE = get(0,'screensize'); displaySIZE = displaySIZE(end-1:end);
            app.UIFigure.Position = [winLOC(1:2) 910,589]; 

            % app.EEGsrate = char(app.EEGSamplingRateTextArea.Value);
            % app.PERCEPTsrate = char(app.PERCEPTSamplingRateTextArea.Value);

            % ~isempty(which('eeglab'))
            % evalin('base', '~exist(''ALLEEG'',''var'')')
            if ~isempty(which('eeglab'))
                if evalin('base', '~exist(''ALLEEG'',''var'')')
                    eeglab; close all;
                end
            else
                errordlg('Install/Load eeglab please.')
            end

            app.FINALsrate = char(app.TargetSamplingRateTextArea.Value);
            app.perceptDATAorg=[];
            app.MainApp=mainapp;

            % app.PERCEPT_ReadEditField.Value=" ";
            % app.EEG_ReadEditField.Value=" ";
            % app.OUTPUT_ReadEditField.Value=" ";
        end

        % Button pushed function: EEGinputButton
        function EEGinputButtonPushed(app, event)
            % isempty(app.PERCEPT_ReadEditField.Value) 
            % ~isempty(app.EEG_ReadEditField.Value) 
            % ~isempty(app.OUTPUT_ReadEditField.Value)

            app.UIFigure.Visible = 'off';
            [fname,dirname] = uigetfile('*.set');
            app.UIFigure.Visible = 'on';

            figure(app.UIFigure)

            if fname ==0
                return;
            end

            app.EEG_ReadEditField.Value = char(fullfile(dirname,fname));
            app.EEGinfile = app.EEG_ReadEditField.Value; 

            % EEG
            [dirname2, fname2, fext2] = fileparts(app.EEGinfile);
            set(app.UIFigure, 'pointer','watch'); drawnow;

            EEG = pop_loadset('filename',[fname2, fext2],'filepath',dirname2); % load set file

            set(app.UIFigure, 'pointer','arrow'); drawnow;

            app.ChannelsListBox.Items = {EEG.chanlocs.labels};
            app.DATAorg = EEG; 
            app.EEGsrate = EEG.srate;
            app.EEGSamplingRateTextArea.Value = num2str(app.EEGsrate);

            try 
                app.ChannelsListBox.Value = {'Cz','FCz'};
            catch
            end
            
            app.OUTPUT_Button.Enable = 'on';
            app.OUTPUT_FILENAME_ReadEditField_2.Enable = 'on';
            
            if ~isempty(app.PERCEPT_ReadEditField.Value) && ~isempty(app.EEG_ReadEditField.Value) && ~isempty(app.OUTPUT_ReadEditField.Value)
                app.DoneButton.Enable = 'on';
            end

            % disp(app.infile)
        end

        % Button pushed function: OUTPUT_Button
        function OUTPUT_ButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            OUTdirname = uigetdir; 
            app.UIFigure.Visible = 'on';
            figure(app.UIFigure)
            if OUTdirname ==0
                return;
            end
            [~,EEGfname] = fileparts(app.EEG_ReadEditField.Value);
            [~,PERCEPTfname, PERCEPText] = fileparts(app.PERCEPT_ReadEditField.Value);


            EEGoutname = fullfile(OUTdirname,[EEGfname '.set']); 
            % PERCEPToutname = fullfile(OUTdirname,[PERCEPTfname PERCEPText]); PERCEPToutname
            

            app.OUTPUT_ReadEditField.Value = EEGoutname;
            app.EEGoutfile = EEGoutname;

            if ~isempty(app.PERCEPT_ReadEditField.Value) && ~isempty(app.EEG_ReadEditField.Value) && ~isempty(app.OUTPUT_ReadEditField.Value)
                app.DoneButton.Enable = 'on';
            end
        end

        % Callback function
        function CHANLOC_ButtonPushed(app, event)
            [fname3,dirname3] = uigetfile('*.*');
            app.ReadEditField_3.Value = fullfile(dirname3,fname3);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % if ~isempty(app.ChannelsListBox.Items)
            %     errordlg('Select at least one channel!')
            % end
            
             chanNUMS = size(app.ChannelsListBox.Value,2);
             tempCHANS = [];
             for it = 1:chanNUMS
                 if it == 1
                    tempCHANS = [tempCHANS char(app.ChannelsListBox.Value{1,it})];
                 else
                    tempCHANS = [tempCHANS ' ,' char(app.ChannelsListBox.Value{1,it})];
                 end
             end
            
            app.EEGchans = tempCHANS; 


            % Call main app's updatePARAMS function
            updatePARAMS(app.MainApp, app.EEGinfile, app.EEGoutfile, app.EEGchans, app.EEGsrate, app.DATAorg, app.PERCEPTinfile, app.PERCEPTsrate, app.perceptDATAorg, app.FINALsrate)
            
            %Close the dialogue

            delete(app)
        end

        % Value changed function: EEGSamplingRateTextArea
        function EEGSamplingRateTextAreaValueChanged(app, event)
            
            
            app.EEGsrate = char(app.EEGSamplingRateTextArea.Value);
            % disp(app.srate)
        end

        % Button pushed function: PERCEPTinputButton
        function PERCEPTinputButtonPushed(app, event)
            
            app.UIFigure.Visible = 'off';
            [fname,dirname] = uigetfile('*.json');
            app.UIFigure.Visible = 'on';
            figure(app.UIFigure)

            if fname ==0
                return;
            end

            set(app.UIFigure, 'pointer','watch'); drawnow;
            app.PERCEPTinfile = char(fullfile(dirname,fname));

            % PERCEPT
            [dirname2, fname2, fext2] = fileparts(app.PERCEPTinfile);
            PERCEPT = jsondecode(fileread(char(fullfile(dirname2,[fname2, fext2])))); % load Percept

            assignin('base','DATAorg',PERCEPT);

            app.perceptDATAorg = PERCEPT; 
            app.PERCEPTsrate=PERCEPT.BrainSenseTimeDomain.SampleRateInHz;
            app.PERCEPTSamplingRateTextArea.Value = num2str(app.PERCEPTsrate);
            % assignin('base','PERCEPT',PERCEPT);
            

            app.PERCEPT_ReadEditField.Value = app.PERCEPTinfile;

            set(app.UIFigure, 'pointer','arrow'); drawnow;
            
            app.OUTPUT_Button.Enable = 'on';
            app.OUTPUT_FILENAME_ReadEditField_2.Enable = 'on';

            if ~isempty(app.PERCEPT_ReadEditField.Value) && ~isempty(app.EEG_ReadEditField.Value) && ~isempty(app.OUTPUT_ReadEditField.Value)
                app.DoneButton.Enable = 'on';
            end


        end

        % Value changed function: OUTPUT_FILENAME_ReadEditField_2
        function OUTPUT_FILENAME_ReadEditField_2ValueChanged(app, event)
            value = app.OUTPUT_FILENAME_ReadEditField_2.Value;

            if ~isempty(value)
                [a,b,c] = fileparts(app.EEGoutfile);


                if contains(value, '.set')
                    OUTname = fullfile(a,value);
                else
                    OUTname = fullfile(a,[value '.set']);
                end

                app.OUTPUT_ReadEditField.Value = OUTname;
                app.EEGoutfile = OUTname;
            else
                app.OUTPUT_ReadEditField.Value = app.EEG_ReadEditField.Value;
                app.EEGoutfile = app.EEG_ReadEditField.Value;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectasetfileLabel = uilabel(app.UIFigure);
            app.SelectasetfileLabel.Text = 'Select a   .set file:';
            app.OutputDirLabel = uilabel(app.UIFigure);
            app.OutputDirLabel.Text = 'Output Dir:';
            app.EEGinputButton = uibutton(app.UIFigure, 'push');
            app.EEGinputButton.ButtonPushedFcn = createCallbackFcn(app, @EEGinputButtonPushed, true);
            app.EEGinputButton.Icon = fullfile(pathToMLAPP, 'mat-folder-open.png');
            app.EEGinputButton.Text = '';
            app.EEG_ReadEditField = uieditfield(app.UIFigure, 'text');
            app.EEG_ReadEditField.Editable = 'off';
            app.OUTPUT_Button = uibutton(app.UIFigure, 'push');
            app.OUTPUT_Button.ButtonPushedFcn = createCallbackFcn(app, @OUTPUT_ButtonPushed, true);
            app.OUTPUT_Button.Icon = fullfile(pathToMLAPP, 'mat-folder-open.png');
            app.OUTPUT_Button.Enable = 'off';
            app.OUTPUT_Button.Text = '';
            app.OUTPUT_ReadEditField = uieditfield(app.UIFigure, 'text');
            app.OUTPUT_ReadEditField.Editable = 'off';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.Step1SelectEEGdataLabel = uilabel(app.UIFigure);
            app.Step1SelectEEGdataLabel.Text = 'Step1: Select EEG data';
            app.Step3SetGeneralParametersLabel = uilabel(app.UIFigure);
            app.Step3SetGeneralParametersLabel.Text = 'Step3: Set General Parameters';
            app.ChannelsListBoxLabel = uilabel(app.UIFigure);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.UIFigure);
            app.ChannelsListBox.Items = {''};
            app.ChannelsListBox.Multiselect = 'on';
            app.ChannelsListBox.Value = {''};
            app.SamplingRateWhenMergerdLabel = uilabel(app.UIFigure);
            app.SamplingRateWhenMergerdLabel.Text = 'Target Sampling Rate:';
            app.TargetSamplingRateTextArea = uitextarea(app.UIFigure);
            app.TargetSamplingRateTextArea.Value = {'500'};
            app.Step2SelectPERCEPTdataLabel = uilabel(app.UIFigure);
            app.Step2SelectPERCEPTdataLabel.Text = 'Step2: Select PERCEPT data';
            app.SelectajsonfileLabel = uilabel(app.UIFigure);
            app.SelectajsonfileLabel.Text = 'Select a   .json file:';
            app.PERCEPTinputButton = uibutton(app.UIFigure, 'push');
            app.PERCEPTinputButton.ButtonPushedFcn = createCallbackFcn(app, @PERCEPTinputButtonPushed, true);
            app.PERCEPTinputButton.Icon = fullfile(pathToMLAPP, 'mat-folder-open.png');
            app.PERCEPTinputButton.Text = '';
            app.PERCEPT_ReadEditField = uieditfield(app.UIFigure, 'text');
            app.PERCEPT_ReadEditField.Editable = 'off';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            app.SamplingRateWhenMergerdLabel_2 = uilabel(app.UIFigure);
            app.SamplingRateWhenMergerdLabel_2.Text = 'When Merged';
            app.CzFCzdefaultselectionLabel = uilabel(app.UIFigure);
            app.CzFCzdefaultselectionLabel.Text = {'Cz/FCz '; '(default selection)'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '*';
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = 'Hz';
            app.EEGSamplingRateTextAreaLabel = uilabel(app.UIFigure);
            app.EEGSamplingRateTextAreaLabel.Text = 'EEG Sampling Rate';
            app.EEGSamplingRateTextArea = uitextarea(app.UIFigure);
            app.EEGSamplingRateTextArea.ValueChangedFcn = createCallbackFcn(app, @EEGSamplingRateTextAreaValueChanged, true);
            app.EEGSamplingRateTextArea.Editable = 'off';
            app.HzLabel_5 = uilabel(app.UIFigure);
            app.HzLabel_5.Text = 'Hz';
            app.PERCEPTSamplingRateTextAreaLabel = uilabel(app.UIFigure);
            app.PERCEPTSamplingRateTextAreaLabel.Text = 'PERCEPT Sampling Rate';
            app.PERCEPTSamplingRateTextArea = uitextarea(app.UIFigure);
            app.PERCEPTSamplingRateTextArea.Editable = 'off';
            app.WillbeautomaticallyloadedLabel = uilabel(app.UIFigure);
            app.WillbeautomaticallyloadedLabel.Text = 'Will be automatically loaded';
            app.WillbeautomaticallyloadedLabel_2 = uilabel(app.UIFigure);
            app.WillbeautomaticallyloadedLabel_2.Text = 'Will be automatically loaded';
            app.OUTPUT_FILENAME_ReadEditField_2 = uieditfield(app.UIFigure, 'text');
            app.OUTPUT_FILENAME_ReadEditField_2.ValueChangedFcn = createCallbackFcn(app, @OUTPUT_FILENAME_ReadEditField_2ValueChanged, true);
            app.OUTPUT_FILENAME_ReadEditField_2.Enable = 'off';
            app.SetoutputfilenameLabel = uilabel(app.UIFigure);
            app.SetoutputfilenameLabel.Text = 'Set output filename:';
            app.SamplingRateWhenMergerdLabel_3 = uilabel(app.UIFigure);
            app.SamplingRateWhenMergerdLabel_3.Text = {'(Optional) '; 'default: EEG filename'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetParam2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end