classdef TrimPERCEPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        ofdisconnectionsLabel          matlab.ui.control.Label
        SelectButton                   matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        DoneButton                     matlab.ui.control.Button
        SelectCorrectDataListBox       matlab.ui.control.ListBox
        SelectCorrectDataListBoxLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MainApp
        channels
        data2
        sameCHANidx2
        outdata
        sameCHANidx2selected
        step
        sr
        Ndisc
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, datastruct, winLOC, EEGdata)

            app.UIFigure.Position=winLOC;

            plot(app.UIAxes, EEGdata);
            set(app.UIAxes,'YTickLabel',[],'XTickLabel',[]);
            app.UIAxes.YAxis.Visible = 'off'; app.UIAxes.XAxis.Visible = 'off';

            if isfield(datastruct,'IndefiniteStreaming')
                Data = datastruct.IndefiniteStreaming;
            else
                if isfield(datastruct,'BrainSenseTimeDomain')
                    Data = datastruct.BrainSenseTimeDomain;
                else
                    error('I cannot find any BrainSenseTimeDomain or IndefiniteStreaming field in your datastruct')
                end
            end

            app.sr = Data(1).SampleRateInHz;
            app.step = 1000/app.sr ;

            app.channels = 1:length(unique({Data.Channel}));
            app.Ndisc = (length(Data) / length(unique({Data.Channel}))) - 1;

            allchans = {Data.Channel};

            sameCHANidx = [];
            for icc = 1:length(app.channels)
                sameCHANidx(icc,:) = find(contains(allchans, allchans{icc}));
            end

            app.sameCHANidx2 = sameCHANidx;
            app.Label.Text = num2str(app.Ndisc);


            if app.Ndisc > 0

                
                app.Label.FontColor = [1 0 0];
                % Append disconnected recordings if there is any
                counter = 0;
                dataDUR=[];
                for ic = 1:app.Ndisc+1 % Go through each disconnection

                    for i = 1:length(app.channels) % Go through all channels within each disconnection

                        counter = counter+1;
                        Data(counter).GlobalPacketSizes = str2num(Data(counter).GlobalPacketSizes);
                        Data(counter).GlobalSequences= str2num(Data(counter).GlobalSequences);

                        if i == 1 % When it is the First channel in each disconnection
                            % Get the TIME that the current recording was started
                            tempT = Data(counter).FirstPacketDateTime;
                            tempT(1:strfind(tempT,'T'))=[];
                            tempT(end-4:end)=[];
                            [~,~,~,hh,mm,ss] = datevec(tempT,'HH:MM:SS');
                            td = duration(hh,mm,ss);
                            Data(counter).FirstPacketDateTime = td;

                            Data(counter).TicksInMses = str2num(Data(counter).TicksInMses);
                            t0 = Data(counter).TicksInMses(1)-app.sr;
                            Data(counter).TicksInMses = Data(counter).TicksInMses - t0;

                            % IF the current loop is after the initial disconnections
                            if ic>1
                                % Get the difference of recording start times in ms
                                % TIME second recording - first recording
                                tdDIFF = milliseconds(Data(counter).FirstPacketDateTime-Data(counter-1).FirstPacketDateTime);
                                tempDIFFinMS = tdDIFF-Data(counter-1).TicksInMses(end);
                                % # of sample points that would have been achieved by the
                                % time where the recording was recovered from disconnect
                                Data(counter).DIFFinSP = tdDIFF/4;
                                % # of sample points that needs to be added at the begining
                                % of this session to account for the disconection
                                Data(counter).ADDthis = Data(counter).DIFFinSP - length(Data(counter-1).TimeDomainData);
                                Data(counter).TicksInMses = [Data(counter-1).TicksInMses(end)+250:...
                                    250:tdDIFF Data(counter).TicksInMses + tdDIFF];

                                Data(counter).GlobalPacketSizes = [repmat([63 62], 1,...
                                    floor(length(Data(counter-1).TicksInMses(end)+250:250:tdDIFF)/2))...
                                    Data(counter).GlobalPacketSizes ];

                                Data(counter).TimeDomainData = [nan(Data(counter).ADDthis,1); Data(counter).TimeDomainData];
                                ptDIFF = sum(Data(counter).GlobalPacketSizes) - length(Data(counter).TimeDomainData);


                            end

                        else
                            if ic>1
                                Data(counter).DIFFinSP = Data(counter-1).DIFFinSP;
                                Data(counter).ADDthis = Data(counter-1).ADDthis;
                                Data(counter).TimeDomainData = [nan(Data(counter).ADDthis,1); Data(counter).TimeDomainData];
                            end
                            Data(counter).TicksInMses = Data(counter-1).TicksInMses;
                            Data(counter).FirstPacketDateTime = Data(counter-1).FirstPacketDateTime;
                            Data(counter).GlobalPacketSizes = Data(counter-1).GlobalPacketSizes;
                        end


                    end
                    dataDUR(ic,:) = [min(Data(counter).TicksInMses) max(Data(counter).TicksInMses)];


                end

                % round(size(app.sameCHANidx2,2)/3)
                if size(app.sameCHANidx2,2)>1 % If there are disconnections, plot to check

                    list = {};
                    for is = 1:size(app.sameCHANidx2,2)

                        ax=subplot(round(size(app.sameCHANidx2,2)/3)+1,3,is,'Parent',app.Panel);
                        plot(ax, Data(app.sameCHANidx2(1,is)).TimeDomainData)
                        ax.YAxis.Visible = 'off'; ax.XAxis.Visible = 'off';
                        set(ax,'YTickLabel',[],'XTickLabel',[]);
                        axis(ax,'square')
                        title(ax,['Percept Recording #' num2str(is)])
                        list{is} = ['Percept Recording #' num2str(is)];
                    end

                    app.SelectCorrectDataListBox.Items=list;
                end

                app.sameCHANidx2selected = app.sameCHANidx2;

            end
    
            if app.Ndisc == 0 % If no disconnections
                app.Label.FontColor = [0 1 0];
                app.sameCHANidx2selected = app.sameCHANidx2;
                % app.sameCHANidx2selected
                % app.channels
                stackDATA=[]; stackDATA2=[]; stackDATA3=[];
                for ic = 1:length(app.channels)
                    stackDATA=[]; stackDATA2=[]; stackDATA3=[];
                    for is = 1:size(app.sameCHANidx2selected,2)
                        % app.sameCHANidx2selected(ic,is)


                        stackDATA = [stackDATA; Data(app.sameCHANidx2selected(ic,is)).TimeDomainData];
                        stackDATA2= [stackDATA2 Data(app.sameCHANidx2selected(ic,is)).GlobalPacketSizes];
                        stackDATA3= [stackDATA3 Data(app.sameCHANidx2selected(ic,is)).TicksInMses];
                    end

                    NewData(ic).GlobalPacketSizes = stackDATA2;
                    NewData(ic).TicksInMses = stackDATA3;
                    NewData(ic).DataVec = stackDATA;
                    NewData(ic).TimeVec = 0:app.step:app.step*length(stackDATA)-1;
                    NewData(ic).Channel = unique({Data(app.sameCHANidx2selected(ic,:)).Channel});
                    NewData(ic).srate = app.sr;
                end

                app.outdata = NewData;

                % app.Panel.AutoResizeChilder = 'on';
                ax = subplot(1,1,1, 'Parent',app.Panel);
                plot(ax, app.outdata(1).DataVec); title(ax,'Combined Percept Trace. Compare it with the above figure')
                ax.YAxis.Visible = 'off'; ax.XAxis.Visible = 'off';
                set(ax,'YTickLabel',[],'XTickLabel',[]);
                app.DoneButton.Enable = 'on';
            end


            app.data2 = Data;
            app.MainApp=mainapp;

        end

        % Value changed function: SelectCorrectDataListBox
        function SelectCorrectDataListBoxValueChanged(app, event)
            value = app.SelectCorrectDataListBox.Value;
            
            app.sameCHANidx2selected = app.sameCHANidx2(:,ismember(app.SelectCorrectDataListBox.Items,value));

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            for ic = 1:length(app.channels)
                stackDATA=[]; stackDATA2=[]; stackDATA3=[];
                for is = 1:size(app.sameCHANidx2selected,2)
                    stackDATA = [stackDATA; app.data2(app.sameCHANidx2selected(ic,is)).TimeDomainData];
                    stackDATA2= [stackDATA2 app.data2(app.sameCHANidx2selected(ic,is)).GlobalPacketSizes];
                    stackDATA3= [stackDATA3 app.data2(app.sameCHANidx2selected(ic,is)).TicksInMses];
                end

                NewData(ic).GlobalPacketSizes = stackDATA2;
                NewData(ic).TicksInMses = stackDATA3;
                NewData(ic).DataVec = stackDATA;
                NewData(ic).TimeVec = 0:app.step:app.step*length(stackDATA)-1;
                NewData(ic).Channel = unique({app.data2(app.sameCHANidx2selected(ic,:)).Channel});
                NewData(ic).srate = app.sr;
            end

            % check
            if ~max(NewData(app.channels(1)).TimeVec == NewData(app.channels(end)).TimeVec); error('Mario did smth wrong, again'); end

            app.outdata = NewData;

            % app.Panel.AutoResizeChilder = 'on';
            ax = subplot(1,1,1, 'Parent',app.Panel);
            plot(ax, app.outdata(1).DataVec); title(ax,'Combined Percept Trace. Compare it with the above figure')
            hold(ax,"on"); plot(ax, find(isnan(app.outdata(1).DataVec)),zeros(size(find(isnan(app.outdata(1).DataVec)))),'r.','MarkerSize',30)
            ax.YAxis.Visible = 'off'; ax.XAxis.Visible = 'off';
            set(ax,'YTickLabel',[],'XTickLabel',[]);
            app.DoneButton.Enable = 'on';


        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)


            updatePERCEPT_DATA(app.MainApp,app.outdata)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG signal to display DBS artifacts')
            zlabel(app.UIAxes, 'Z')
            app.SelectCorrectDataListBoxLabel = uilabel(app.UIFigure);
            app.SelectCorrectDataListBoxLabel.Text = 'Select Correct Data';
            app.SelectCorrectDataListBox = uilistbox(app.UIFigure);
            app.SelectCorrectDataListBox.Items = {};
            app.SelectCorrectDataListBox.Multiselect = 'on';
            app.SelectCorrectDataListBox.ValueChangedFcn = createCallbackFcn(app, @SelectCorrectDataListBoxValueChanged, true);
            app.SelectCorrectDataListBox.Value = {};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.Panel = uipanel(app.UIFigure);
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ofdisconnectionsLabel = uilabel(app.UIFigure);
            app.ofdisconnectionsLabel.Text = '# of disconnections:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimPERCEPT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end