classdef PEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PEAPerceptEEGAlignmentUIFigure  matlab.ui.Figure
        SelectedChannelsLabel_3         matlab.ui.control.Label
        EEG_ArtFreqEditField            matlab.ui.control.EditField
        SelectedChannelsLabel_2         matlab.ui.control.Label
        ChangeEEGChannelsButton         matlab.ui.control.Button
        OUTPUTsrateEditField            matlab.ui.control.EditField
        perceptLABEL                    matlab.ui.control.Label
        eegLABEL                        matlab.ui.control.Label
        PERCEPTsrateEditField           matlab.ui.control.EditField
        PERCEPT_InputFileEditField      matlab.ui.control.EditField
        EEGsrateEditField               matlab.ui.control.EditField
        SamplingRateHzLabel             matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        PEAPerceptEEGAlignmentv10Label  matlab.ui.control.Label
        Percept_ArtNumEditField         matlab.ui.control.EditField
        ofDBSartifactsEditField_2Label  matlab.ui.control.Label
        EEG_ArtNumEditField             matlab.ui.control.EditField
        ofDBSartifactsEditFieldLabel    matlab.ui.control.Label
        step4LABEL                      matlab.ui.control.Label
        step3LABEL                      matlab.ui.control.Label
        step2LABEL                      matlab.ui.control.Label
        step1LABEL                      matlab.ui.control.Label
        Percept_DetectLabel             matlab.ui.control.Label
        EEG_DetectLabel                 matlab.ui.control.Label
        OUTPUTEditField                 matlab.ui.control.EditField
        OUTPUTEditFieldLabel            matlab.ui.control.Label
        EEG_FileNameEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        EEG_SelectedChannelsEditField   matlab.ui.control.EditField
        SelectedChannelsLabel           matlab.ui.control.Label
        MergedataButton                 matlab.ui.control.Button
        FindPERCEPTDBSartifactsButton   matlab.ui.control.Button
        FindEEGDBSartifactsButton       matlab.ui.control.Button
        SetParametersButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        DATAorg % Description
        DATAorg2
        DATAorg_PERCEPT
        DATAorg_PERCEPT2
        
        EEGfreq
        EEGartNUM
        PERCEPTartNUM
        EEGartLAT
        PERCEPTartLAT

        EEGcheckflag
        PERCEPTcheckflag

        selectCHANidx
        
    end
    
    methods (Access = public)
        
        function updatePARAMS(app, EEGinfile, EEGoutfile, EEGchans, EEGsrate, DATAorg, PERCEPTinfile, PERCEPTsrate, perceptDATAorg, FINALsrate)
            % app.DataTypeEditField.Value = settings_datatype;
            app.EEG_SelectedChannelsEditField.Value = EEGchans;
            app.EEG_FileNameEditField.Value = EEGinfile;
            % app.OUTPUTEditField.Value = EEGoutfile;
            app.EEGsrateEditField.Value = num2str(EEGsrate);
            app.PERCEPT_InputFileEditField.Value = PERCEPTinfile;
            app.PERCEPTsrateEditField.Value = num2str(PERCEPTsrate);
            app.OUTPUTsrateEditField.Value = FINALsrate;
            app.OUTPUTEditField.Value= EEGoutfile;


            app.DATAorg = DATAorg; %EEG data with Original sampling rate
            app.DATAorg_PERCEPT = perceptDATAorg; %Load PERCEPT data
            app.FindEEGDBSartifactsButton.Enable = 'on';

            figure(app.PEAPerceptEEGAlignmentUIFigure)
        end


        function updateEEG(app,checkflag,DATAorg2,EEGartNUM,EEGartLAT,selectCHANidx)

            app.DATAorg2 = DATAorg2; %Re-sampled to the desired sampling rate
            app.EEGartNUM = EEGartNUM; 
            app.EEG_ArtNumEditField.Value = num2str(app.EEGartNUM);
            app.EEGartLAT = EEGartLAT;
            app.selectCHANidx = selectCHANidx; 
            
            if checkflag
                app.EEGcheckflag = true; 
                app.EEG_DetectLabel.Text = 'COMPLETE!';
                app.EEG_DetectLabel.FontColor = [0 1 0];
                app.FindPERCEPTDBSartifactsButton.Enable = 'on';
                figure(app.PEAPerceptEEGAlignmentUIFigure)
            end

            
        end

        function changeEEGchans(app,newEEGchans)
            tempCHAN=[];
            for ic = 1:length(newEEGchans)
                
                if ic == length(newEEGchans)
                    tempCHAN = [tempCHAN newEEGchans{ic} ];
                else
                    tempCHAN = [tempCHAN newEEGchans{ic} ','];
                end
            end
            
            
            app.EEG_SelectedChannelsEditField.Value = tempCHAN;
        end

        function updatePERCEPT(app,checkflag,DATAorg_PERCEPT,PERCEPTartNUM,PERCEPTartLAT)
            
            app.DATAorg_PERCEPT2 = DATAorg_PERCEPT; %Re-sampled to the desired sampling rate
            app.PERCEPTartNUM = PERCEPTartNUM; 
            app.Percept_ArtNumEditField.Value = num2str(app.PERCEPTartNUM);
            app.PERCEPTartLAT = PERCEPTartLAT;
            
            if checkflag
                app.PERCEPTcheckflag = true; 
                app.Percept_DetectLabel.Text = 'COMPLETE!';
                app.Percept_DetectLabel.FontColor = [0 1 0];
                figure(app.PEAPerceptEEGAlignmentUIFigure)
                
            end


            if app.EEGcheckflag && app.PERCEPTcheckflag
                app.MergedataButton.Enable = 'on';
            end
        end
        
        
    end
    
    methods (Access = private)
        
        function CursorChange(app)
            if app.workflag
                set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','watch'); drawnow;
            else
                set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','arrow'); drawnow;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SFname=dir( 'PEA*'); %Find a subfolder that includes scripts for sub-modules of this toolbox
            addpath(fullfile(SFname.folder,'PeaBasket'))
            

            displaySIZE = get(0,'screensize'); displaySIZE = displaySIZE(end-1:end);
            app.PEAPerceptEEGAlignmentUIFigure.Position = [displaySIZE(1)/20, displaySIZE(2)*(3/6), 798,465];
            format longG
            warning off
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)

            
            SetParam2(app,app.PEAPerceptEEGAlignmentUIFigure.Position)


        end

        % Button pushed function: FindEEGDBSartifactsButton
        function FindEEGDBSartifactsButtonPushed(app, event)
            
            EEGfreqRANGE = eval(app.EEG_ArtFreqEditField.Value);
            checkflag=false; app.EEGcheckflag = false; 
            DATAorg11 = app.DATAorg; 
            srate = app.EEGsrateEditField.Value;
            EEGchans2 = split(app.EEG_SelectedChannelsEditField.Value,',');

            set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','watch'); drawnow;
            
            EEG_DBSartDETECT(app,checkflag, DATAorg11, EEGchans2,srate,app.PEAPerceptEEGAlignmentUIFigure.Position, app.OUTPUTsrateEditField.Value,EEGfreqRANGE)

            set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','arrow'); drawnow;


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            delete(app)
            PEA
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            format default
            warning on
            delete(app)
        end

        % Button pushed function: FindPERCEPTDBSartifactsButton
        function FindPERCEPTDBSartifactsButtonPushed(app, event)
            checkflag=false; app.PERCEPTcheckflag = false; 
            DATAorg11 = app.DATAorg_PERCEPT; 
            srate = app.PERCEPTsrateEditField.Value;
            set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','watch'); drawnow;
            if length(app.selectCHANidx)>1
                tempEEGdata = squeeze(mean(app.DATAorg.data(app.selectCHANidx,:)));
            elseif length(app.selectCHANidx)==1
                tempEEGdata = squeeze(app.DATAorg.data(app.selectCHANidx,:));
            end
            PERCEPT_DBSartDETECT(app,checkflag, DATAorg11,srate,app.PEAPerceptEEGAlignmentUIFigure.Position, tempEEGdata, app.OUTPUTsrateEditField.Value)
            set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','arrow'); drawnow;

            
        end

        % Button pushed function: MergedataButton
        function MergedataButtonPushed(app, event)

            epochVAL = 24; % Epoch oneside for the final alignment plot

            TARGETsrate = str2double(app.OUTPUTsrateEditField.Value); 

            set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','watch'); drawnow;

            EEGchans = app.EEG_SelectedChannelsEditField.Value; 

            assignin('base','EEGchans',EEGchans);


            % Trim then insert Percept data into the eeglab file
            EEG = MergeBSandEEG_cs(app.DATAorg_PERCEPT2,app.DATAorg2, app.PERCEPTartLAT);
            try
                EEG = pop_select( EEG, 'rmchannel',length({EEG.chanlocs.labels}));
            catch
                EEG = pop_select( EEG, 'nochannel',length({EEG.chanlocs.labels}));
            end
            assignin('base','EEG_merged',EEG);
            [a b c ] = fileparts(app.OUTPUTEditField.Value);

            % Save the merged file
            EEG2 = EEG; 


            % CONTINUOUS DATA PLOT
            if length(app.selectCHANidx)>1
                EEGtraceWHOLE = squeeze(mean(EEG.data(app.selectCHANidx,:)));
            elseif length(app.selectCHANidx)==1
                EEGtraceWHOLE = squeeze(EEG.data(app.selectCHANidx,:));
            end
            EEGtraceWHOLE = EEGtraceWHOLE-mean(EEGtraceWHOLE(1:10000));
            PERCEPTtraceWHOLE = squeeze(EEG.data(end-1,:));
            
            artlats = [EEG.event(strcmpi({EEG.event.type},'DBS_ARTIFACT')).latency];
            
            if ~isempty(find(isnan(PERCEPTtraceWHOLE))) %IF there are any disconnections
                nanIDX = find(isnan(PERCEPTtraceWHOLE));
                NoNanIDX = find(~isnan(PERCEPTtraceWHOLE));

                PERCEPTtraceWHOLE = (PERCEPTtraceWHOLE - mean(PERCEPTtraceWHOLE,'omitnan')) ./ std(PERCEPTtraceWHOLE,'omitnan');
                
                % figure; plot(zscore(EEGtraceWHOLE),'k'); hold on; plot(PERCEPTtraceWHOLE,'r','LineWidth',2)
            
                figure; plot(PERCEPTtraceWHOLE,'k','LineWidth',2); hold on; 

                % PERCEPTtraceWHOLE([1280000:1290000 1350000:1380000])=nan;

                tempIDX = find(isnan(PERCEPTtraceWHOLE));
                
                % Find any time windows where the diconnections happened in
                % between DBS artifacts. Segments between the disconnection
                % and the next DBS artifacts MUST be removed from the data.
                % Because BrainSense device does not provide millisecond
                % precision temporal data. Thus precise sync between the
                % EEG and Percept data is impossible. 

                rmvIDX = []; xIDX = []; yIDX=[];
                for ib = 1:length(artlats)
                    
                    if ~isempty(find(tempIDX<artlats(ib)))
                        disp(['block' num2str(ib)])

                        tempIDX2=[tempIDX(find(tempIDX<artlats(ib),1,'first')) (artlats(ib)-epochVAL-1)] ;
                        rmvIDX=[rmvIDX; tempIDX2];
                        tempIDX( find(tempIDX<artlats(ib))<(artlats(ib)-1) )=[];
                        xIDX = [xIDX; [tempIDX2(1) tempIDX2(1) tempIDX2(2) tempIDX2(2)] ];
                    end
                end

                plot(find(isnan(PERCEPTtraceWHOLE)),zeros(size(find(isnan(PERCEPTtraceWHOLE)))),'c.','MarkerSize',30)
                xline(artlats,'r'); hold on;

                tempYLIM = get(gca, 'YLim');

                yIDX = repmat([tempYLIM(1) tempYLIM(2) tempYLIM(2) tempYLIM(1)],size(xIDX,1),1);
                tempCOLOR = repmat([0 1 0],size(xIDX,1),1);
                
                for ib = 1:size(xIDX,1)
                    fill(xIDX(ib,:), yIDX(ib,:), tempCOLOR(ib,:),'FaceAlpha',.1,'LineStyle','none');
                end

                title({'PERCEPTtrace \color{red}DBS marks';'\color{cyan} Disconnected period'; '\color{green} Bad SYNC window removal suggested!'},'FontSize',20)
                
                answer = questdlg('Delete as suggested?', ...
                	' ', ...
                	'Yes','No','No');
                % Handle response
                switch answer
                    case 'Yes'
                        EEG2 = pop_select( EEG2, 'nopoint',rmvIDX); 
                    case 'No'
                        rmvlats = Click2DeleteTimepoints(EEG2.data(end-1,:));
                        EEG2 = pop_select( EEG2, 'nopoint',rmvlats); % Delete with manually selected windows 
                end

                % Delete segments. Boundary events are inserted so won't be included when epoched
                
                EEG = pop_saveset( EEG2, 'filename',[b c],'filepath',a );

                artlats2 = [EEG2.event(strcmpi({EEG2.event.type},'DBS_ARTIFACT')).latency];
                
                if length(app.selectCHANidx)>1
                    EEGtraceWHOLE2 = squeeze(mean(EEG.data(app.selectCHANidx,:)));
                elseif length(app.selectCHANidx)==1
                    EEGtraceWHOLE2 = squeeze(EEG.data(app.selectCHANidx,:));
                end

                PERCEPTtraceWHOLE2 = squeeze(EEG2.data(end-1,:));
                PERCEPTtraceWHOLE2 = (PERCEPTtraceWHOLE2 - mean(PERCEPTtraceWHOLE2,'omitnan')) ./ std(PERCEPTtraceWHOLE2,'omitnan');


                figure; 
                subplot(211)
                
                 
                plot(PERCEPTtraceWHOLE,'r','LineWidth',2); hold on; 
                plot(EEGtraceWHOLE,'k','LineWidth',2); 
                % PERCEPTtraceWHOLE([1280000:1290000 1350000:1380000])=nan;
                
                plot(find(isnan(PERCEPTtraceWHOLE)),zeros(size(find(isnan(PERCEPTtraceWHOLE)))),'c.','MarkerSize',30)
                xline(artlats,'r'); hold on;

                tempYLIM = get(gca, 'YLim');

                yIDX = repmat([tempYLIM(1) tempYLIM(2) tempYLIM(2) tempYLIM(1)],size(xIDX,1),1);
                tempCOLOR = repmat([0 1 0],size(xIDX,1),1);
                
                if strcmp(answer, 'Yes')
                    for ib = 1:size(xIDX,1)
                        fill(xIDX(ib,:), yIDX(ib,:), tempCOLOR(ib,:),'FaceAlpha',.1,'LineStyle','none');
                    end

                elseif strcmp(answer, 'No')
                    
                    for ib = 1:size(rmvlats,1)
                        fill([rmvlats(ib,1) rmvlats(ib,1) rmvlats(ib,2) rmvlats(ib,2)], yIDX(1,:), tempCOLOR(1,:),'FaceAlpha',.1,'LineStyle','none');
                    end
                end


                xlim([1 length(PERCEPTtraceWHOLE)])
                title({'BEFORE trimming the data'; 'EEGtrace \color{red}PERCEPTtrace \color{cyan}Disconnection'},'FontSize',20)

                subplot(212)
                plot(PERCEPTtraceWHOLE2,'r','LineWidth',2); hold on; 
                plot(EEGtraceWHOLE2,'k','LineWidth',2);
                
                plot(find(isnan(PERCEPTtraceWHOLE2)),zeros(size(find(isnan(PERCEPTtraceWHOLE2)))),'c.','MarkerSize',30)
                xline(artlats2,'r'); xlim([1 length(PERCEPTtraceWHOLE)])
                title({'AFTER trimming the data'; 'EEGtrace \color{red}PERCEPTtrace'},'FontSize',20)

                % figure; plot(zscore(EEG.data(strcmpi({EEG.chanlocs.labels},'Cz'),:)),'k'); hold on; plot(PERCEPTtraceWHOLE2,'r','LineWidth',2)

            else
                EEG = pop_saveset( EEG2, 'filename',[b c],'filepath',a );
                assignin('base','EEG',EEG); 
                figure; plot([0 diff(zscore(EEGtraceWHOLE))],'k'); hold on; plot(zscore(PERCEPTtraceWHOLE),'r','LineWidth',2)
            
                figure; plot(zscore(PERCEPTtraceWHOLE),'k','LineWidth',2); hold on; 
                plot(find(isnan(PERCEPTtraceWHOLE)),zeros(size(find(isnan(PERCEPTtraceWHOLE)))),'r.','MarkerSize',30)
            end


            % EPOCHED DATA PLOT
            % epochVAL = 24; % in ms
            
            EEG = pop_epoch( EEG, {  'DBS_ARTIFACT'  }, [-1*epochVAL/1000, (epochVAL+(1000/TARGETsrate))/1000]);
            EEG = pop_rmbase( EEG, [-1*epochVAL (-1*epochVAL)+(epochVAL*.4)] ,[]);


            if length(app.selectCHANidx)>1
                EEGtrace = squeeze(mean(EEG.data(app.selectCHANidx,:,:)));
            elseif length(app.selectCHANidx)==1
                EEGtrace = squeeze(EEG.data(app.selectCHANidx,:,:));
            end

            PERCEPTtrace = squeeze(EEG.data(end-1,:,:));

            tNUM = size(EEGtrace,2);
            
            figure('Position',[500 500 1500 1500]); 
            
            for it = 1:tNUM
                subplot(floor(tNUM/4)+1, 4, it)
                plot(-epochVAL:1000/TARGETsrate:epochVAL,zscore(EEGtrace(:,it)),'k-'); hold on
                plot(-epochVAL:1000/TARGETsrate:epochVAL,zscore(PERCEPTtrace(:,it)),'m-');
                xline(0,'k')
                tempylimVAL = get(gca,'YLim');
                set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2])
                axis square
                title({ 'ARTIFACT alignment' ; [num2str(it) ' / ' num2str(tNUM)] })
                xlabel({'Time (ms)' 'Zero is DBS ARTIFACT'})
                ylabel('Normalized Amplitude')
                xlim([-1 1]*epochVAL)
                tempylimVAL = get(gca,'YLim');
                tempxlimVAL = get(gca,'XLim');
                text(tempxlimVAL(1)*.95,tempylimVAL(2)*.8,{'\color{black} EEG'; '\color{magenta} PERCEPT'},'FontSize',10)
                
            end
            set(gcf,'Units','normalized');
            set(gcf,'Position',[0.5152    0.6361   0.5200    0.7150])
            % figure; plot(EEGtrace-median(EEGtrace)); hold on; 
            % plot(PERCEPTtrace)
            set(app.PEAPerceptEEGAlignmentUIFigure, 'pointer','arrow'); drawnow;

            % if ~isempty(find(isnan(PERCEPTtraceWHOLE))) %IF there are any disconnections
            % 
            %     % Delete segments. Boundary events are inserted so won't be included when epoched
            %     EEG2 = pop_select( EEG2, 'nopoint',rmvIDX); 
            %     EEG = pop_saveset( EEG2, 'filename',[b c],'filepath',a );
            % 
            %     artlats2 = [EEG2.event(strcmpi({EEG2.event.type},'DBS_ARTIFACT')).latency];
            % 
            %     PERCEPTtraceWHOLE2 = squeeze(EEG2.data(end-1,:));
            % 
            %     PERCEPTtraceWHOLE2 = (PERCEPTtraceWHOLE2 - mean(PERCEPTtraceWHOLE2,'omitnan')) ./ std(PERCEPTtraceWHOLE2,'omitnan');
            % 
            % 
            %     figure; 
            %     subplot(211)
            %     plot(PERCEPTtraceWHOLE,'k','LineWidth',2); hold on; 
            %     % PERCEPTtraceWHOLE([1280000:1290000 1350000:1380000])=nan;
            % 
            %     plot(find(isnan(PERCEPTtraceWHOLE)),zeros(size(find(isnan(PERCEPTtraceWHOLE)))),'r.','MarkerSize',30)
            %     xline(artlats,'r'); hold on;
            % 
            %     tempYLIM = get(gca, 'YLim');
            % 
            %     yIDX = repmat([tempYLIM(1) tempYLIM(2) tempYLIM(2) tempYLIM(1)],size(xIDX,1),1);
            %     tempCOLOR = repmat([0 1 0],size(xIDX,1),1);
            % 
            %     for ib = 1:size(xIDX,1)
            %         fill(xIDX(ib,:), yIDX(ib,:), tempCOLOR(ib,:),'FaceAlpha',.1,'LineStyle','none');
            %     end
            % 
            %     xlim([1 length(PERCEPTtraceWHOLE)])
            %     title('BEFORE trimming the data')
            % 
            %     subplot(212)
            %     plot(PERCEPTtraceWHOLE2,'k','LineWidth',2); hold on; 
            %     plot(find(isnan(PERCEPTtraceWHOLE2)),zeros(size(find(isnan(PERCEPTtraceWHOLE2)))),'r.','MarkerSize',30)
            %     xline(artlats2,'r'); xlim([1 length(PERCEPTtraceWHOLE)])
            %     title('AFTER trimming the data')
            % end


        end

        % Button pushed function: ChangeEEGChannelsButton
        function ChangeEEGChannelsButtonPushed(app, event)
            ChangeEEGchannelsAPP(app,app.DATAorg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PEAPerceptEEGAlignmentUIFigure = uifigure('Visible', 'off');
            app.PEAPerceptEEGAlignmentUIFigure.Name = 'PEA (Percept EEG Alignment)';
            app.SetParametersButton = uibutton(app.PEAPerceptEEGAlignmentUIFigure, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.FindEEGDBSartifactsButton = uibutton(app.PEAPerceptEEGAlignmentUIFigure, 'push');
            app.FindEEGDBSartifactsButton.ButtonPushedFcn = createCallbackFcn(app, @FindEEGDBSartifactsButtonPushed, true);
            app.FindEEGDBSartifactsButton.Enable = 'off';
            app.FindEEGDBSartifactsButton.Text = {'Find EEG '; 'DBS artifacts'};
            app.FindPERCEPTDBSartifactsButton = uibutton(app.PEAPerceptEEGAlignmentUIFigure, 'push');
            app.FindPERCEPTDBSartifactsButton.ButtonPushedFcn = createCallbackFcn(app, @FindPERCEPTDBSartifactsButtonPushed, true);
            app.FindPERCEPTDBSartifactsButton.Enable = 'off';
            app.FindPERCEPTDBSartifactsButton.Text = {'Find PERCEPT '; 'DBS artifacts'};
            app.MergedataButton = uibutton(app.PEAPerceptEEGAlignmentUIFigure, 'push');
            app.MergedataButton.ButtonPushedFcn = createCallbackFcn(app, @MergedataButtonPushed, true);
            app.MergedataButton.Enable = 'off';
            app.MergedataButton.Text = 'Merge data';
            app.SelectedChannelsLabel = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.SelectedChannelsLabel.Text = 'Selected Channels';
            app.EEG_SelectedChannelsEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.EEG_SelectedChannelsEditField.Editable = 'off';
            app.FileNameEditFieldLabel = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.EEG_FileNameEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.EEG_FileNameEditField.Editable = 'off';
            app.OUTPUTEditFieldLabel = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.OUTPUTEditFieldLabel.Text = 'OUTPUT';
            app.OUTPUTEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.OUTPUTEditField.Editable = 'off';
            app.EEG_DetectLabel = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.EEG_DetectLabel.Text = 'NOT DETECTED!';
            app.Percept_DetectLabel = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.Percept_DetectLabel.Text = 'NOT DETECTED!';
            app.step1LABEL = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.step1LABEL.Text = 'Step 1';
            app.step2LABEL = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.step2LABEL.Text = 'Step 2';
            app.step3LABEL = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.step3LABEL.Text = 'Step 3';
            app.step4LABEL = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.step4LABEL.Text = 'Step 4';
            app.ofDBSartifactsEditFieldLabel = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.ofDBSartifactsEditFieldLabel.Text = '# of DBS artifacts';
            app.EEG_ArtNumEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.EEG_ArtNumEditField.Editable = 'off';
            app.ofDBSartifactsEditField_2Label = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.ofDBSartifactsEditField_2Label.Text = '# of DBS artifacts';
            app.Percept_ArtNumEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.Percept_ArtNumEditField.Editable = 'off';
            app.PEAPerceptEEGAlignmentv10Label = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.PEAPerceptEEGAlignmentv10Label.Text = 'PEA (Percept EEG Alignment) v1.0';
            app.ResetButton = uibutton(app.PEAPerceptEEGAlignmentUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset!';
            app.CloseButton = uibutton(app.PEAPerceptEEGAlignmentUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SamplingRateHzLabel = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.SamplingRateHzLabel.Text = 'Sampling Rate';
            app.EEGsrateEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.EEGsrateEditField.Editable = 'off';
            app.PERCEPT_InputFileEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.PERCEPT_InputFileEditField.Editable = 'off';
            app.PERCEPTsrateEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.PERCEPTsrateEditField.Editable = 'off';
            app.eegLABEL = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.eegLABEL.Text = 'EEG';
            app.perceptLABEL = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.perceptLABEL.Text = 'PERCEPT';
            app.OUTPUTsrateEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.OUTPUTsrateEditField.Editable = 'off';
            app.ChangeEEGChannelsButton = uibutton(app.PEAPerceptEEGAlignmentUIFigure, 'push');
            app.ChangeEEGChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeEEGChannelsButtonPushed, true);
            app.ChangeEEGChannelsButton.Text = {'Change'; 'EEG Channels'};
            app.SelectedChannelsLabel_2 = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.SelectedChannelsLabel_2.Text = {'Freq Range to  Find '; 'DBS artifacts in EEG'};
            app.EEG_ArtFreqEditField = uieditfield(app.PEAPerceptEEGAlignmentUIFigure, 'text');
            app.EEG_ArtFreqEditField.Value = '125:130';
            app.SelectedChannelsLabel_3 = uilabel(app.PEAPerceptEEGAlignmentUIFigure);
            app.SelectedChannelsLabel_3.Text = 'Default: 125:130 Hz';
            % Show the figure after all components are created
            app.PEAPerceptEEGAlignmentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PEAPerceptEEGAlignmentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PEAPerceptEEGAlignmentUIFigure)
        end
    end
end