classdef ChangeEEGchannelsAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DoneButton               matlab.ui.control.Button
        EEGChannelsListBox       matlab.ui.control.ListBox
        EEGChannelsListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
        selectEEGchans % Description
        allEEGchans
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, EEGdata)
            app.EEGChannelsListBox.Items ={EEGdata.chanlocs.labels};
            app.MainApp=mainapp;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % app.EEGChannelsListBox.Value
            app.selectEEGchans = app.EEGChannelsListBox.Value;
            changeEEGchans(app.MainApp,app.selectEEGchans)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EEGChannelsListBoxLabel = uilabel(app.UIFigure);
            app.EEGChannelsListBoxLabel.Text = 'EEG Channels';
            app.EEGChannelsListBox = uilistbox(app.UIFigure);
            app.EEGChannelsListBox.Multiselect = 'on';
            app.EEGChannelsListBox.Value = {'Item 1'};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangeEEGchannelsAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end