classdef EEGManualAdj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Next        matlab.ui.control.Button
        Prev        matlab.ui.control.Button
        DoneButton  matlab.ui.control.Button
        MoveRight   matlab.ui.control.Button
        MoveLeft    matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp
        it2
        tNUM
        data2 % entire continuous data
        EEGtrace2 % epoched data
        epochVAL
        selectCHANidx2
    end
    
    methods (Access = private)
        
        function CheckPlot(app, data, epochVAL, it)
            % Enable/Disable Next/Prev buttons
            

            temp_data = pop_epoch( data, {  'DBS_ARTIFACT'  }, [-1*epochVAL/1000, (epochVAL+1000/app.data2.srate)/1000]);
            temp_data = pop_rmbase( temp_data, [-1*epochVAL (-1*epochVAL)+(epochVAL*.4)] ,[]);

            if it==1
                app.Next.Enable = 'on';
                app.Prev.Enable = 'off';
            end
            if it>1
                app.Next.Enable = 'on';
                app.Prev.Enable = 'on';
            end
            % size(temp_data.data)
            if it == size(temp_data.data,3)
                app.Next.Enable = 'off';
                app.Prev.Enable = 'on';
            end


            if length(app.selectCHANidx2)>1
                selectedCHANdata = squeeze(mean(temp_data.data(app.selectCHANidx2,:,:)));
            elseif length(app.selectCHANidx2)==1
                selectedCHANdata = squeeze(temp_data.data(app.selectCHANidx2,:,:));
            end

            selectedCHANdata = selectedCHANdata ./ repmat(max(abs(selectedCHANdata)), length(selectedCHANdata),1);

            EEGtrace=selectedCHANdata( :,it );

            hold(app.UIAxes,"off");

            plot(app.UIAxes,-epochVAL:1000/app.data2.srate:epochVAL,zscore(EEGtrace),'k-'); hold(app.UIAxes,"on");
            xline(app.UIAxes,0,'k')
            tempylimVAL = get(app.UIAxes,'YLim');
            set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2])
            axis square
            title(app.UIAxes,{ ['ARTIFACT alignment (sampling rate: ' num2str(data.srate) ' Hz)']; [num2str(it) ' / ' num2str(length(temp_data.epoch))] })
            xlabel(app.UIAxes,{'Time (ms)' 'Zero is DBS ARTIFACT'})
            ylabel(app.UIAxes,'Normalized Amplitude')

            close all
        end

        function CheckALL_Plot(app, data, epochVAL)
            temp_data = pop_epoch( data, {  'DBS_ARTIFACT'  }, [-1*epochVAL/1000, (epochVAL+1000/app.data2.srate)/1000]);
            temp_data = pop_rmbase( temp_data, [-1*epochVAL (-1*epochVAL)+(epochVAL*.4)] ,[]);

            if length(app.selectCHANidx2)>1
                selectedCHANdata = squeeze(mean(temp_data.data(app.selectCHANidx2,:,:)));
            elseif length(app.selectCHANidx2)==1
                selectedCHANdata = squeeze(temp_data.data(app.selectCHANidx2,:,:));
            end

            EEGtrace = selectedCHANdata ./ repmat(max(abs(selectedCHANdata)), length(selectedCHANdata),1);
            
            tNUM = size(EEGtrace,2);
            

            figure('Position',[500 500 1500 1500]);
            for it=1:size(selectedCHANdata,2)
                subplot(floor(tNUM/4)+1, 4, it)
                plot(-epochVAL:1000/data.srate:epochVAL,zscore(EEGtrace(:,it)),'k-'); hold on;
                xline(0,'k')
                tempylimVAL = get(gca,'YLim');
                set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2])
                axis square
                title({ ['ARTIFACT alignment (sampling rate: ' num2str(data.srate) ' Hz)']; [num2str(it) ' / ' num2str(length(temp_data.epoch))] })
                xlabel({'Time (ms)' 'Zero is DBS ARTIFACT'})
                ylabel('Normalized Amplitude')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data, epochVAL, selectCHANidx)
            
            app.epochVAL=epochVAL;
            app.data2 = data; 
            app.it2 = 1;
            app.selectCHANidx2 = selectCHANidx;


            data2 = pop_epoch( app.data2, {  'DBS_ARTIFACT'  }, [-1*app.epochVAL/1000, (app.epochVAL+1000/app.data2.srate)/1000]);
            data2 = pop_rmbase( data2, [-1*epochVAL (-1*app.epochVAL)+(app.epochVAL*.4)] ,[]);

            app.tNUM = length(data2.epoch);

            if length(selectCHANidx)>1
                selectedCHANdata = squeeze(mean(data2.data(selectCHANidx,:,:)));
            elseif length(selectCHANidx)==1
                selectedCHANdata = squeeze(data2.data(selectCHANidx,:,:));
            end

            selectedCHANdata = selectedCHANdata ./ repmat(max(abs(selectedCHANdata)), length(selectedCHANdata),1);

            EEGtrace=selectedCHANdata( :,app.it2 );
            
            plot(app.UIAxes,-epochVAL:1000/app.data2.srate:epochVAL,zscore(EEGtrace),'k-'); hold(app.UIAxes,"on");
            xline(app.UIAxes,0,'k')
            tempylimVAL = get(app.UIAxes,'YLim');
            set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2])
            axis square
            title(app.UIAxes,{['ARTIFACT alignment (sampling rate: ' num2str(app.data2.srate) ' Hz)'] ; [num2str(app.it2) ' / ' num2str(app.tNUM)] })
            xlabel(app.UIAxes,{'Time (ms)' 'Zero is DBS ARTIFACT'})
            ylabel(app.UIAxes,'Normalized Amplitude')
            
            close all

            app.Next.Enable = 'on';
            app.Prev.Enable = 'off';
            app.MainApp=mainapp;
        end

        % Button pushed function: MoveLeft
        function MoveLeftButtonPushed(app, event)
            tempEV = app.data2.event(strcmp({app.data2.event.type},'DBS_ARTIFACT'));
            thisLAT = tempEV(app.it2).latency;

            if length([app.data2.event.latency ]) == length(app.data2.event)
                app.data2.event( find([app.data2.event.latency ]==thisLAT) ).latency = thisLAT-1;
            end

            CheckPlot(app, app.data2, app.epochVAL, app.it2)
        end

        % Button pushed function: MoveRight
        function MoveRightButtonPushed(app, event)
            tempEV = app.data2.event(strcmp({app.data2.event.type},'DBS_ARTIFACT'));
            thisLAT = tempEV(app.it2).latency;

            if length([app.data2.event.latency ]) == length(app.data2.event)
                app.data2.event( find([app.data2.event.latency ]==thisLAT) ).latency = thisLAT+1;
            end

            CheckPlot(app, app.data2, app.epochVAL, app.it2)
        end

        % Button pushed function: Prev
        function PrevButtonPushed(app, event)
            app.it2 = app.it2-1;
            CheckPlot(app, app.data2, app.epochVAL, app.it2)
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            app.it2 = app.it2+1;
            CheckPlot(app, app.data2, app.epochVAL, app.it2)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            % CheckALL_Plot(app, app.data2, app.epochVAL)

            % Call main app's updatePARAMS function
            updateEEG_ARTLAT(app.MainApp, app.data2)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MoveLeft = uibutton(app.UIFigure, 'push');
            app.MoveLeft.ButtonPushedFcn = createCallbackFcn(app, @MoveLeftButtonPushed, true);
            app.MoveLeft.Text = '-1';
            app.MoveRight = uibutton(app.UIFigure, 'push');
            app.MoveRight.ButtonPushedFcn = createCallbackFcn(app, @MoveRightButtonPushed, true);
            app.MoveRight.Text = '+1';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Prev = uibutton(app.UIFigure, 'push');
            app.Prev.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.Prev.Text = 'Previous';
            app.Next = uibutton(app.UIFigure, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGManualAdj(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end