classdef EEG_DBSartDETECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReviewButton                 matlab.ui.control.Button
        ManualWindowSelectionButton  matlab.ui.control.Button
        ZoomSlider                   matlab.ui.control.Slider
        ZoomSliderLabel              matlab.ui.control.Label
        TimesSlider                  matlab.ui.control.Slider
        Move                         matlab.ui.control.Button
        Select                       matlab.ui.control.Button
        ZoomOUT                      matlab.ui.control.Button
        ZoomIN                       matlab.ui.control.Button
        PrevButton                   matlab.ui.control.Button
        NextXButton                  matlab.ui.control.Button
        CheckDBSONspikeButton        matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MainApp % Description
        checkflag
        DATAorg2
        DATAorg3
        EEGchans
        IDX2
        selectCHANdata2
        srate2
        selectCHANidx
        TARGETsrate2
        medval2

        it2
        maxval2
        threshMULTIPLIER


        x
        y

        xx2

        winBOTTOM
        winUPPER
        winCHANGE
        zoomCHANGE

        CHECKflag
        EEGartNUM
        EEGartLAT

        epochVAL


    end

    
    methods (Access = public)
        
        function updateEEG_ARTLAT(app, revisedEEG)
            app.DATAorg3 = revisedEEG;
            app.EEGartLAT = [app.DATAorg3.event(strcmp({app.DATAorg3.event.type},'DBS_ARTIFACT')).latency];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, checkflag, DATAorg, EEGchans, srate, winLOC, TARGETsrate, EEGfreqRange)
            displaySIZE = get(0,'screensize'); displaySIZE = displaySIZE(end-1:end);
            app.UIFigure.Position = [winLOC(1:2) 1372,799];
            % size(DATAorg)
            app.DATAorg2 = DATAorg;
        
            % Default chans : Cz, FCz

            % Turn srate into double
            srate=str2double(srate);

            chanNUM = length(EEGchans);
            for ic = 1:chanNUM
                tempCHECK = strfind(char(EEGchans(ic)), ' ');
                if ~isempty(tempCHECK)
                    EEGchans{ic}(tempCHECK)=[];
                end
            end

            % EEGchans
            selectCHANS = find(ismember({DATAorg.chanlocs.labels},EEGchans));
            app.selectCHANidx = selectCHANS;

            if length(selectCHANS) > 1
                DATAorgAVG = mean(DATAorg.data(selectCHANS,:));
            elseif length(selectCHANS) == 1
                DATAorgAVG = DATAorg.data(selectCHANS,:);
            end


            selectCHANdata22 = DATAorgAVG;
            % Remove any slow drift
            selectCHANdata22= [0 diff(selectCHANdata22)];
            % figure; plot((selectCHANdata2))
            % figure; plot(abs(selectCHANdata2))

            % wflag=false;
            % medval = median(selectCHANdata22(selectCHANdata22~=0));
            % if medval<0; medval = 1; end
            % thresh = 3; increment = .5;
            % 
            % while ~wflag
            %     rmvIDX = selectCHANdata22<medval*thresh ;
            %     selectCHANdata22(rmvIDX)=0;
            % 
            %     zeroPERCENT = sum(selectCHANdata22==0)/length(selectCHANdata22);
            % 
            %     if zeroPERCENT > .995
            %         wflag = true;
            %     else
            %         thresh = thresh + increment;
            %     end
            % end
            
            [a,b]=sort(selectCHANdata22,'descend');
            selectCHANdata22(b(round(length(selectCHANdata22)*.005)+1 : end))=0;


            EEGfreqRange
            
            wavelet_conv_data2 = CMersp(selectCHANdata22,srate,EEGfreqRange);
            AVGxdata = mean(abs(wavelet_conv_data2));
            AVGxdata(AVGxdata<median(AVGxdata(AVGxdata>0))*3)=0;
            % figure; plot(AVGxdata)


            % [a tempIDXsm]=findpeaks(AVGxdata,'MinPeakDistance',srate*60,'MinPeakWidth',srate*2);
            [a tempIDXsm]=findpeaks(AVGxdata,'MinPeakDistance',srate*20,'MinPeakWidth',srate*2);

            swinVAL = (srate*30);

            if ~isempty(tempIDXsm)
                if tempIDXsm(1)<swinVAL
                    swinVAL = tempIDXsm(1) - 10;
                end

                if length(selectCHANdata22)-tempIDXsm(end)<swinVAL
                    swinVAL = length(selectCHANdata22)-tempIDXsm(end) - 10;
                end


                for it = 1:length(tempIDXsm)
                    swin = selectCHANdata22((tempIDXsm(it)-swinVAL):(tempIDXsm(it)+swinVAL));

                    % If the captured point is closer to DBS ART offset latency
                    % get the latency close to the onset
                    if sum(swin(1:swinVAL)~=0) > sum(swin(end-swinVAL:end)~=0)
                        tempIDXsm(it) = tempIDXsm(it) - (swinVAL - find(swin(1:swinVAL)~=0,1,'first'));
                    end
                end

            else 
                % IF stimulation-related artifact detection algorithm does not work
                % Plot some random stuffs and the user need to manually
                % define windows.
                tempIDXsm = [5000 5000 5000 5000];

            end


            % Find when each DBS artifact started
            DBSstart = tempIDXsm;
            DBSend = DBSstart;


            if length(DBSstart) ~= length(DBSend)
                errordlg('DBS art detection WRONG!')
            end

            if length(DBSstart) == length(DBSend)
                app.x = zeros(1,length(DBSstart));
                app.y = zeros(1,length(DBSstart));
            end


            % Define timepoints according to the given srate
            % timepoints = 1/srate:1/srate:length(DATAorgAVG)/srate;
            plot(app.UIAxes,DATAorgAVG,'k'); hold on
            xline(app.UIAxes,DBSstart,'r');

            try
                close(1)
            catch
            end
            % This is how you set X/YLim in app designer
            % xlim(app.UIAxes,[min(timepoints) max(timepoints)])
            xlim(app.UIAxes,[1 length(DATAorgAVG)])
            title(app.UIAxes, ['CHANNELS: ' EEGchans{:}])

            plot(app.UIAxes_2,DATAorgAVG,'k');
            title(app.UIAxes_2, 'Zoomed in epoch')
            xlim(app.UIAxes_2,[1 length(DATAorgAVG)])


            app.IDX2 = [DBSstart; DBSend]';
            app.selectCHANdata2=DATAorgAVG;
            app.srate2=srate;
            app.it2 = 1;
            app.winCHANGE=0;
            app.CHECKflag=false;
            app.zoomCHANGE=0;
            app.EEGchans = EEGchans;
            app.epochVAL = 10;
            app.TARGETsrate2 = str2double(TARGETsrate);
            app.threshMULTIPLIER = 10^4;
            app.MainApp=mainapp;


        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            
            % DOWNSAMPLE to 500 Hz
            DATAorg500 = pop_resample( app.DATAorg3, app.TARGETsrate2); % DATA modified with medianx10 values to mark DBS artifacts
           
            
            % Find values over median x 10^4
            selectCHANdata2 = DATAorg500.data(end,:);
            DBSONartLAT2 = find(selectCHANdata2>app.medval2*app.threshMULTIPLIER*.5); 
            DBSONartLAT2(find(diff(DBSONartLAT2)<DATAorg500.srate)+1)=[];

            % All DBS_ARTIFACT EV latencies are replaced with these
            % accurate ones
            tempDBSevIDX = find(strcmpi({DATAorg500.event.type},'DBS_ARTIFACT'));

             if length(tempDBSevIDX) ~= length(DBSONartLAT2)
                error('# of DBS marks not matched with identified artifacts')
             end

            counter = 0;
            for it = tempDBSevIDX
                counter=counter+1;
                DATAorg500.event(it).latency = DBSONartLAT2(counter);
            end

            DATAorg500 = pop_select( DATAorg500, 'nochannel',DATAorg500.nbchan);

             assignin('base','DATAorg500',DATAorg500);
    
            data2 = pop_epoch( DATAorg500, {  'DBS_ARTIFACT'  }, [-1*app.epochVAL/1000, (app.epochVAL+(1000/DATAorg500.srate))/1000]);
            data2 = pop_rmbase( data2, [-1*app.epochVAL (-1*app.epochVAL)+(app.epochVAL*.4)] ,[]);

            if length(app.selectCHANidx)>1
                selectedCHANdata = squeeze(mean(data2.data(app.selectCHANidx,:,:)));
            elseif length(app.selectCHANidx)==1
                selectedCHANdata = squeeze(data2.data(app.selectCHANidx,:,:));
            end
            
            EEGtrace = selectedCHANdata ./ repmat(max(abs(selectedCHANdata)), size(selectedCHANdata,1),1);
            tNUM = size(EEGtrace,2);

            % figure('Position',[500 500 1500 1500]);
            % 
            % for it = 1:tNUM
            %     subplot(floor(tNUM/4)+1, 4, it)
            %     plot(-app.epochVAL:1000/DATAorg500.srate:app.epochVAL,EEGtrace(:,it),'k-'); hold on
            %     xline(0,'k')
            %     tempylimVAL = get(gca,'YLim');
            %     set(gca, 'YLim', [tempylimVAL(1)*1.2 tempylimVAL(2)*1.2])
            %     axis square
            %     title({ ['ARTIFACT alignment (sampling rate: ' num2str(DATAorg500.srate) ' Hz)'] ; [num2str(it) ' / ' num2str(tNUM)] })
            %     xlabel({'Time (ms)' 'Zero is DBS ARTIFACT'})
            %     ylabel('Normalized Amplitude')
            %     xlim([-1 1]*app.epochVAL)
            %     tempylimVAL = get(gca,'YLim');
            %     tempxlimVAL = get(gca,'XLim');
            %     % text(tempxlimVAL(1)*.95,tempylimVAL(2)*.8,{'\color{black} EEG'},'FontSize',10)
            % 
            % end

            app.EEGartNUM = length(DBSONartLAT2);
            app.EEGartLAT = DBSONartLAT2;
            app.DATAorg3 = DATAorg500;
            app.checkflag = true;


            % Call main app's updatePARAMS function
            updateEEG(app.MainApp,app.checkflag, app.DATAorg3,app.EEGartNUM,app.EEGartLAT, app.selectCHANidx)

            %Close the dialogue
            delete(app)


        end

        % Button pushed function: ReviewButton
        function ReviewButtonPushed(app, event)
            set(app.UIFigure, 'pointer','watch'); drawnow;
            % Get channel indices within EEG.chanloc.labels
            chans = find(ismember({app.DATAorg2.chanlocs.labels},app.EEGchans));

            % Setup two variables from the raw data
            DATAorg2=app.DATAorg2; % Insert artificial values to mark artifacts
            DATAorg3=app.DATAorg2; % Raw EEG trace + DBS artifact marked event struct

            % Get a template EV to be used a DBS_ARTIFACT EV later
            tempEV = DATAorg2.event;
            templateEVidx = find(~isnan([tempEV.duration]),1,'first');
            templateEV = tempEV(templateEVidx);
            tempEVart = [];
            if isempty(templateEV); templateEV = tempEV; end

            % Setup a DBS_ARTIFACT marking value based on a median across
            % throughout the entire data in the selected channels
            % DBS_ARTIFACTS are manually marked because downsampling the
            % data will mess up with the EV latencies.
            if length(chans)>1
                medval = median(mean(DATAorg2.data(chans,:))); %This is the median value from selected channels (Cz, FCz at default)
            elseif length(chans)==1
                medval = median(DATAorg2.data(chans,:)); %This is the median value from selected channels (Cz, FCz at default)
            end

            if medval<0; medval = -1*medval; end
            if abs(medval)<1; medval = 1; end

            
            app.medval2 = medval; 

            DBSONartLAT = ceil(app.x); %DBS_ARTIFACT latencies @sampling rate:5000 Hz


            % This loop takes EVs from tempEV to tempEVart then insert DBS
            % ARTIFACT EV until the last DBS ARTIFACT.
            for it = 1:length(DBSONartLAT)
                % Get all EVs before the current DBS ARTIFACT latency
                tempEVart = [tempEVart tempEV([tempEV.latency]<DBSONartLAT(it))];
                % Remove all EVs before the current DBS ARTIFACT latency
                tempEV([tempEV.latency]<DBSONartLAT(it))=[];

                % Create a DBS ARTIFACT event
                thisEV = templateEV;
                thisEV.latency = DBSONartLAT(it); thisEV.duration = NaN;
                thisEV.channel = 0; thisEV.bvtime = []; thisEV.bvmknum = NaN;
                thisEV.type = 'DBS_ARTIFACT';
                thisEV.code = 'Stimulus'; thisEV.urevent = NaN;
                
                % Compatibility w/ old eeglab
                if ismember('visual',fieldnames(tempEVart))
                    thisEV.visible = '';
                end


                % Insert the current ART latency as an event
                tempEVart = [tempEVart thisEV];

                % When last DBS ARTIFACT, insert the rest of tempEV in the
                % end of tempEVart
                if it == length(DBSONartLAT)
                    tempEVart = [tempEVart tempEV];
                end

                % Artificial values (median x 10^4) are inserted to 10 points
                % here because we are downsampling from 5000Hz to 500Hz
                DATAorg2.data(:,DBSONartLAT(it):DBSONartLAT(it)+9) = medval*app.threshMULTIPLIER;
            end


            % DATAorg2 EEG trace was inserted with artificial values to
            % mark DBS ARTIFACTs

            % Replace the existing EEG.event with the one that includes DBS_ARTIFACT EVs
            DATAorg2.event = tempEVart;
            if length(app.EEGchans)>1 % IF multiple channels, average across them
                selectCHANdata2 = mean(DATAorg2.data(find(ismember({DATAorg2.chanlocs.labels},app.EEGchans)),:));
            elseif length(app.EEGchans) == 1 % IF single channel, no averaging
                selectCHANdata2 = DATAorg2.data(find(ismember({DATAorg2.chanlocs.labels},app.EEGchans)),:);
            end

            DATAorg3.event = tempEVart;
            
            % Insert selecteeCHANdata2 into the EEG struct as the manually
            % added last channel. This will be removed later. 
            % This was done to run downsampling just once. 
            DATAorg3.data = [DATAorg3.data; selectCHANdata2]; 
            DATAorg3.chanlocs(size(DATAorg3.data,1)).labels = 'TempSELECTEDchans';
            DATAorg3.nbchan = size(DATAorg3.data,1);

            app.DATAorg3 = DATAorg3;
            
            set(app.UIFigure, 'pointer','watch'); drawnow;
            EEGManualAdj(app,app.DATAorg3, app.epochVAL, app.selectCHANidx)
            set(app.UIFigure, 'pointer','arrow'); drawnow;

            app.ReviewButton.Enable = 'off';
            app.DoneButton.Enable = 'on';
            
        end

        % Button pushed function: CheckDBSONspikeButton
        function CheckDBSONspikeButtonPushed(app, event)
            app.ManualWindowSelectionButton.Enable = 'off';
            app.CHECKflag=true;

            app.CheckDBSONspikeButton.Enable = 'off';
            app.Move.Enable = 'on';
            app.ZoomOUT.Enable = 'on';
            app.ZoomIN.Enable = 'on';
            app.Select.Enable = 'on';
            app.TimesSlider.Enable = 'on';

            selectCHANdata = app.selectCHANdata2;
            srate=app.srate2;


            IDX = app.IDX2;

            it = app.it2;
            % Enable/Disable Next/Prev buttons
            if it==1
                app.NextXButton.Enable = 'on';
                app.PrevButton.Enable = 'off';
            end
            if it>1
                app.NextXButton.Enable = 'on';
                app.PrevButton.Enable = 'on';
            end
            if it == size(IDX,1)
                app.NextXButton.Enable = 'off';
                app.PrevButton.Enable = 'on';
            end

            padding = srate*20;


            if floor(IDX(it,1)-srate*20) < 0
                xlimVAL = [1 floor(IDX(it,2)+(srate*20))];
            elseif floor(IDX(it,2)+srate*20) > length(selectCHANdata)
                xlimVAL = [floor(IDX(it,1)-srate*20) length(selectCHANdata)];
            else
                xlimVAL = [floor(IDX(it,1)-padding) floor(IDX(it,2)+padding)];
            end
            
            app.winUPPER = xlimVAL;


            xlim(app.UIAxes, xlimVAL);
            title(app.UIAxes,'Broader epoch range')


            win = [(floor(IDX(it,1))-(srate*2)) (floor(IDX(it,1))+(srate*2))]; 
            winRANGE = diff(win); 
            winINCREMENT = winRANGE*(1-app.zoomCHANGE); 
            winINCREMENT = winINCREMENT/2;
            win = [floor(IDX(it,1))-winINCREMENT, floor(IDX(it,1))+winINCREMENT] + app.winCHANGE; 
            
            app.winBOTTOM=win;

            % win
            xlim(app.UIAxes_2,win);
            temp=double(app.selectCHANdata2(win(1):win(2)));

            winVAL =  [min(temp) max(temp)];
            marg = .1;
            scaleVAL1 = winVAL + [-1 1]*diff(winVAL)*marg;
            scaleVAL2 = winVAL + [1 -1]*diff(winVAL)*marg;
            scaleVAL3 = winVAL + [1 1]*diff(winVAL)*marg;

            if max(temp) > 0 && min(temp) > 0
                ylim(app.UIAxes_2,scaleVAL1);
            elseif max(temp) < 0 && min(temp) < 0
                ylim(app.UIAxes_2,scaleVAL1);
            elseif max(temp) > 0 && min(temp) < 0
                ylim(app.UIAxes_2,scaleVAL1);
            end


            title(app.UIAxes_2,{['Trial#: ' num2str(it) ' / ' num2str(size(IDX,1))]; 'Zoomed in window'})
            xline(app.UIAxes, win, 'g');

            Ytemp = get(app.UIAxes,'YLim');

            hold(app.UIAxes, 'on')
            x_points = [win(1), win(1), win(2), win(2)]; 
            y_points = [Ytemp(1) Ytemp(2) Ytemp(2) Ytemp(1)];
            fill(app.UIAxes, x_points, y_points, [0 1 0],'FaceAlpha',.1);


            try
                close(1)
            catch
            end


        end

        % Button pushed function: NextXButton
        function NextXButtonPushed(app, event)


            if app.it2 <= length(app.IDX2)
                app.it2 = app.it2 + 1;
                CheckDBSONspikeButtonPushed(app, event)
            else
                app.NextXButton.Enable = 'off';

            end


        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.it2 = app.it2 - 1;
            CheckDBSONspikeButtonPushed(app, event)
        end

        % Button pushed function: ZoomIN
        function ZoomINPushed(app, event)

            zoom(app.UIAxes_2,'on')

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.CHECKflag
                eventname = event.EventName;
                % event
                switch eventname
                    case 'WindowMouseMotion'
                        % Obtain Coordinates of mouse for each of the 4 axes
                        currentPoint1 = app.UIAxes_2.CurrentPoint(1,1:3);
                        x1 = currentPoint1(1);
                        y1 = currentPoint1(2);

                        % Obtain Coordinates of mouse for each of the 4 axes
                        currentPoint2 = app.UIAxes.CurrentPoint(1,1:3);
                        x2 = currentPoint2(1);
                        y2 = currentPoint2(2);


                        % Change pointer to crosshair when within axes.
                        if (app.UIAxes_2.XLim(1)<x1)&&(x1<app.UIAxes_2.XLim(2)) && (app.UIAxes_2.YLim(1)<y1)&&(y1<app.UIAxes_2.YLim(2))
                            set(app.UIFigure,'Pointer','cross');
                        elseif(app.UIAxes.XLim(1)<x2)&&(x2<app.UIAxes.XLim(2)) && (app.UIAxes.YLim(1)<y2)&&(y2<app.UIAxes.YLim(2))
                            set(app.UIFigure,'Pointer','cross');
                        else
                            set(app.UIFigure,'Pointer','arrow'); % When outside of axes, return pointer to arrow
                        end


                end

            end
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            % event
            eventname = event.EventName;
            eventBUTTON = event.Button;

            if app.CHECKflag

                if eventBUTTON == 1
                    switch eventname

                        case 'Hit'
                            % When mouse is pressed, get coordinates
                            currentPoint1 = get(app.UIAxes_2,'CurrentPoint');
                            currentPoint2 = get(app.UIAxes,'CurrentPoint');
                            xx=ceil(currentPoint1(1,1));
                            yy=currentPoint1(1,2);

                            xx2 = ceil(currentPoint2(1,1));
                            app.xx2 = xx2; 


                            tempRANGE = 100;
                            tempEPOCH = app.selectCHANdata2((xx-tempRANGE):(xx+tempRANGE));
                            blVAL = mean(tempEPOCH(1:10));
                            tempEPOCH = tempEPOCH - blVAL; 

                            if tempEPOCH(tempRANGE) > 0
                                [~,tempX] = max(tempEPOCH); 
                            elseif tempEPOCH(tempRANGE) < 0
                                [~,tempX] = min(tempEPOCH);
                            end
                            tempX = tempX + (xx-tempRANGE) -1 ;


                            if app.x(app.it2)==0
                                hold(app.UIAxes_2,'on');
                                plot(app.UIAxes_2, tempX,app.selectCHANdata2(tempX), 'ro','MarkerFaceColor','r','MarkerSize',8);
                            else
                                hold(app.UIAxes_2,'off');
                                plot(app.UIAxes_2,app.selectCHANdata2,'k');
                                win=app.winBOTTOM;
                                it = app.it2;
                                IDX = app.IDX2;

                                xlim(app.UIAxes_2,win);
                                temp=double(app.selectCHANdata2(win(1):win(2)));
                                winVAL =  [min(temp) max(temp)];
                                marg = .1;
                                scaleVAL1 = winVAL + [-1 1]*diff(winVAL)*marg;
                                scaleVAL2 = winVAL + [1 -1]*diff(winVAL)*marg;
                                scaleVAL3 = winVAL + [1 1]*diff(winVAL)*marg;

                                if max(temp) > 0 && min(temp) > 0
                                    ylim(app.UIAxes_2,scaleVAL1);
                                elseif max(temp) < 0 && min(temp) < 0
                                    ylim(app.UIAxes_2,scaleVAL1);
                                elseif max(temp) > 0 && min(temp) < 0
                                    ylim(app.UIAxes_2,scaleVAL1);
                                end


                                title(app.UIAxes_2,{['Trial#: ' num2str(it) ' / ' num2str(size(IDX,1))]; 'Zoom into the artifact then press anykey'})

                                restXtemp = app.x( setxor(1:length(app.x),app.it2) );

                                if ~isempty(restXtemp(restXtemp~=0))
                                    % xline(app.UIAxes_2, restXtemp(restXtemp~=0), 'c','LineWidth',3);
                                end


                                hold(app.UIAxes_2,'on');
                                plot(app.UIAxes_2, [restXtemp tempX],app.selectCHANdata2(tempX), 'ro','MarkerFaceColor','r','MarkerSize',8);

                                try
                                    close(1)
                                catch
                                end
                            end

                            app.x(app.it2) = tempX; %app.x
                            app.y(app.it2) = currentPoint1(1,2); %app.y


                            % DONE button activated when all ART
                            % latencies were found thus no zero values
                            % in app.x
                            if ~ismember(0,app.x)
                                app.ReviewButton.Enable = 'on';
                            end


                    end
                end

                if eventBUTTON == 3
                    % app.cflag=true;
                    zoom(app.UIAxes_2,'off')
                    pan(app.UIAxes_2, 'off')
                end
                figure(app.UIFigure)
            end
        end

        % Button pushed function: ZoomOUT
        function ZoomOUTButtonPushed(app, event)
            zoom(app.UIAxes_2,'out')
        end

        % Button pushed function: Select
        function SelectButtonPushed(app, event)
            zoom(app.UIAxes_2,'off')
            pan(app.UIAxes_2, 'off')
        end

        % Button pushed function: Move
        function MoveButtonPushed(app, event)
            pan(app.UIAxes_2, 'on')

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            % event
            % key
            if app.CHECKflag
                if strcmp(event.EventName,'WindowKeyPress')

                    % key
                    switch key
                        case 'a'
                            zoom(app.UIAxes_2,'on')
                        case 's'
                            zoom(app.UIAxes_2,'out')
                        case 'd'
                            pan(app.UIAxes_2, 'on');
                        case 'z'
                            if app.it2 ~= 1
                                app.it2 = app.it2-1;
                                CheckDBSONspikeButtonPushed(app, event)
                            end
                        case 'x'
                            if app.it2 <= length(app.IDX2)
                                app.it2 = app.it2 + 1;
                                CheckDBSONspikeButtonPushed(app, event)
                            else
                                app.NextXButton.Enable = 'off';
                            end
                        case 'leftarrow'
                            slideVAL = app.TimesSlider.Value;
                            app.TimesSlider.Value = app.TimesSlider.Value - .2;
                            TimesSliderValueChanged(app, event)
                        case 'rightarrow'
                            slideVAL = app.TimesSlider.Value;
                            app.TimesSlider.Value = app.TimesSlider.Value + .2;
                            TimesSliderValueChanged(app, event)
                        case 'uparrow'
                            zoomVAL = app.ZoomSlider.Value;
                            app.ZoomSlider.Value = app.ZoomSlider.Value + 2;
                            ZoomSliderValueChanged(app, event)
                        case 'downarrow'
                            zoomVAL = app.ZoomSlider.Value;
                            app.ZoomSlider.Value = app.ZoomSlider.Value - 2;
                            ZoomSliderValueChanged(app, event)
                    end


                end
            end
        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            value = app.TimesSlider.Value;
            app.winCHANGE = (value*app.srate2); 
            % app.winUPPER
            hold(app.UIAxes,'off')
            plot(app.UIAxes,app.selectCHANdata2,'k'); %hold on
            xline(app.UIAxes,app.IDX2(:,1),'r');
            xlim(app.UIAxes, app.winUPPER);
            title(app.UIAxes,'Broader epoch range')
            try close(1);catch;end


            CheckDBSONspikeButtonPushed(app, event)
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            value = app.ZoomSlider.Value;
            value = value/100;
            app.zoomCHANGE = value; 

            % app.zoomCHANGE
            hold(app.UIAxes, 'off')
            plot(app.UIAxes,app.selectCHANdata2,'k'); hold on
            xline(app.UIAxes,app.IDX2(:,1),'r');
            xlim(app.UIAxes, app.winUPPER);
            title(app.UIAxes,'Broader epoch range')
            try
                close(1)
            catch
            end

            CheckDBSONspikeButtonPushed(app, event)
        end

        % Button pushed function: ManualWindowSelectionButton
        function ManualWindowSelectionButtonPushed(app, event)
            DBSstart = Click2DefineWin(app.selectCHANdata2);
            app.IDX2 = [DBSstart; DBSstart]';
            app.x = zeros(1,length(DBSstart));
            % % Define timepoints according to the given srate
            % % timepoints = 1/srate:1/srate:length(DATAorgAVG)/srate;
            figure; plot(app.UIAxes,app.selectCHANdata2,'k');
            xline(app.UIAxes,app.IDX2(:,1),'r')

            try
                close(1)
            catch
            end
            % This is how you set X/YLim in app designer
            % % xlim(app.UIAxes,[min(timepoints) max(timepoints)])
            xlim(app.UIAxes,[1 length(app.selectCHANdata2)])
            title(app.UIAxes, ['CHANNELS: ' app.EEGchans{:}])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wider Scale')
            xlabel(app.UIAxes, 'Time (Sample Point)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Zoomed in')
            xlabel(app.UIAxes_2, 'Time (Sample Point)')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.CheckDBSONspikeButton = uibutton(app.UIFigure, 'push');
            app.CheckDBSONspikeButton.ButtonPushedFcn = createCallbackFcn(app, @CheckDBSONspikeButtonPushed, true);
            app.CheckDBSONspikeButton.Text = {'Check'; 'DBS ON spike'};
            app.NextXButton = uibutton(app.UIFigure, 'push');
            app.NextXButton.ButtonPushedFcn = createCallbackFcn(app, @NextXButtonPushed, true);
            app.NextXButton.Enable = 'off';
            app.NextXButton.Text = 'Next [X]';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Enable = 'off';
            app.PrevButton.Text = 'Previous [Z]';
            app.ZoomIN = uibutton(app.UIFigure, 'push');
            app.ZoomIN.ButtonPushedFcn = createCallbackFcn(app, @ZoomINPushed, true);
            app.ZoomIN.Enable = 'off';
            app.ZoomIN.Text = 'Zoom IN [A]';
            app.ZoomOUT = uibutton(app.UIFigure, 'push');
            app.ZoomOUT.ButtonPushedFcn = createCallbackFcn(app, @ZoomOUTButtonPushed, true);
            app.ZoomOUT.Enable = 'off';
            app.ZoomOUT.Text = 'Zoom OUT [S]';
            app.Select = uibutton(app.UIFigure, 'push');
            app.Select.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.Select.Enable = 'off';
            app.Select.Text = 'Select';
            app.Move = uibutton(app.UIFigure, 'push');
            app.Move.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.Move.Enable = 'off';
            app.Move.Text = 'Navigate [D]';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.Limits = [-20 20];
            app.TimesSlider.MajorTicks = [-20 -10 0 10 20];
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.TimesSlider.Enable = 'off';
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.Limits = [40 90];
            app.ZoomSlider.MajorTicks = [40 50 60 70 80 90];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.Value = 40;
            app.ManualWindowSelectionButton = uibutton(app.UIFigure, 'push');
            app.ManualWindowSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ManualWindowSelectionButtonPushed, true);
            app.ManualWindowSelectionButton.Text = {'Manual Window'; 'Selection'};
            app.ReviewButton = uibutton(app.UIFigure, 'push');
            app.ReviewButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewButtonPushed, true);
            app.ReviewButton.Enable = 'off';
            app.ReviewButton.Text = 'Review';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_DBSartDETECT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end